classdef GUI_CalibrateNeedle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeedleCalibrationUIFigure      matlab.ui.Figure
        SetZHeightButton               matlab.ui.control.Button
        CalibratedButton               matlab.ui.control.Button
        NeedlePositioningPanel         matlab.ui.container.Panel
        MoveUp                         matlab.ui.control.Button
        MoveDown                       matlab.ui.control.Button
        RountTrip                      matlab.ui.control.Button
        ZHeightumEditFieldLabel        matlab.ui.control.Label
        ZHeight                        matlab.ui.control.NumericEditField
        StepSizeumEditFieldLabel       matlab.ui.control.Label
        StepSize                       matlab.ui.control.NumericEditField
        LimitsOverride                 matlab.ui.control.CheckBox
        ZeroButton                     matlab.ui.control.StateButton
        ImageGuidedCalibrationPanel    matlab.ui.container.Panel
        NeedleNotVisibleButton         matlab.ui.control.Button
        NeedleVisibleButton            matlab.ui.control.Button
        CalculateNeedlePositionButton  matlab.ui.control.Button
        XYCalibratedLampLabel          matlab.ui.control.Label
        XYCalibratedLamp               matlab.ui.control.Lamp
        Img1Lamp                       matlab.ui.control.Lamp
        Img2Lamp                       matlab.ui.control.Lamp
        UseManualSelectionsButton      matlab.ui.control.StateButton
        XLabel                         matlab.ui.control.Label
        XManual                        matlab.ui.control.NumericEditField
        YLabel                         matlab.ui.control.Label
        YManual                        matlab.ui.control.NumericEditField
        ZCalibratedLamp                matlab.ui.control.Lamp
        TestcodeButton                 matlab.ui.control.StateButton
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global LED sm2 shield2 ard2 NeedlePos
            
            %Turn on LED backlight
            powervalue=num2str(250);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
            %%%%% Initialize Arduino to control Needle actuator
            %Create arduino object with adafruit library
            ard2 = arduino('COM6','Uno','Libraries','Adafruit/MotorShieldV2');
            %Create an add-on shield object
            shield2 = addon(ard2,'Adafruit/MotorShieldV2');
            %Create a stepper motor object; Port 2, 24 steps/rotation, 50 rpm, 2 coils
            %activated (double) for more torque
            sm2 = stepper(shield2,2,24,'RPM',8,'stepType','Double');
            %Set Speed
            sm2.RPM = 8;
            
            %Initialize a matrix to hold positions of needle x and y positions and z height at first contact
            NeedlePos = zeros(1,3);


        end

        % Close request function: NeedleCalibrationUIFigure
        function NeedleCalibrationUIFigureCloseRequest(app, event)
            global himage TIMER mmc vid LED sm2 shield2 ard2
            
            %% Move needle to origin before closing
            %This step cannot be performed if the 2x objective is installed: needle arm will hit the objective face
            NeedleStep = 51;
            if app.ZHeight.Value >15000
                move(sm2,round((app.ZHeight.Value-15000)/NeedleStep));
                release(sm2);
            end
            %%%%% Disconnect from Arduino (Needle actuator)
            %Clear arduino variables from global variable space
            sm2 = [];
            shield2 = [];
            ard2 = [];
            
            %Close sequence acquisition
            mmc.stopSequenceAcquisition();
            stop(TIMER)
            %             delete(timerfindall)
            delete(TIMER)
            close(vid)
            clear -global TIMER
            
            % turn off brightfield light
            powervalue=num2str(0);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
            delete(app)
            
        end

        % Button pushed function: CalibratedButton
        function CalibratedButtonPushed(app, event)
            global himage TIMER mmc vid LED sm2 shield2 ard2 NeedlePos PDIR
            DIR = PDIR;
            
            if any(NeedlePos(:)<1)
                f = msgbox('Calibration Not Complete');
            else
                %Move needle to just above objective before closing
                NeedleStep = 51;
                if app.ZHeight.Value >15000
                    move(sm2,round((app.ZHeight.Value-15000)/NeedleStep));
                    release(sm2);
                end
                %%%%% Disconnect from Arduino (Needle actuator)
                %Clear arduino variables from global variable space
                sm2 = [];
                shield2 = [];
                ard2 = [];
                
                %Close sequence acquisition
                mmc.stopSequenceAcquisition();
                stop(TIMER)
%             delete(timerfindall)
            delete(TIMER)
                close(vid)
                clear -global TIMER
                
                % turn off brightfield light
                powervalue=num2str(0);
                fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                pause(0.5)
                while LED.BytesAvailable>0
                    LEDMessage = fscanf(LED);
                end
                save([DIR,'\data\NeedlePos'],'NeedlePos');
            end
            
            delete(app)
        end

        % Button pushed function: MoveUp
        function MoveUpPushed(app, event)
            global sm2
            %% Extend Needle (move upwards in z direction; towards microraft array)
            NeedleStep = 51;
            if (app.ZHeight.Value + app.StepSize.Value) <= 31000
                move(sm2,-app.StepSize.Value/NeedleStep);
                release(sm2);
                app.ZHeight.Value = app.ZHeight.Value + app.StepSize.Value;
            end
            
        end

        % Button pushed function: MoveDown
        function MoveDownPushed(app, event)
            global sm2
            %% Retract Needle (move downwards in z direction; away from microraft array)
            %Needle step size: 51 microns per step
            NeedleStep = 51;
            % Retract the needle if greater than 14mm OR if override is on; override is used
            %when the actuator starts at a non zero value
            %14mm bottom limit keeps the release device from colliding with 2x objective
            if (app.ZHeight.Value - app.StepSize.Value) >= 14000 && app.LimitsOverride.Value == 0
                move(sm2,app.StepSize.Value/NeedleStep);
                release(sm2);
                app.ZHeight.Value = app.ZHeight.Value - app.StepSize.Value;
            elseif app.LimitsOverride.Value == 1
                move(sm2,app.StepSize.Value/NeedleStep);
                release(sm2);
                app.ZHeight.Value = 0;
            end
        end

        % Button pushed function: RountTrip
        function RountTripPushed(app, event)
            global sm2
            %% Extend and then retract needle equal distance
            NeedleStep = 51;
            %Caution; set travel limits may be larger than physical limits due to zeroing changes
            %Physical limit is set to max travel of actuator
            if (app.ZHeight.Value + app.StepSize.Value) <= 31000
                move(sm2,-app.StepSize.Value/NeedleStep)
                pause(0.1);
                %app.ZHeight.Value = app.ZHeight.Value + app.StepSize.Value;
                move(sm2,app.StepSize.Value/NeedleStep);
                %app.ZHeight.Value = app.ZHeight.Value - app.StepSize.Value;
                release(sm2);
            end
        end

        % Value changed function: StepSize
        function StepSizeValueChanged(app, event)
            value = app.StepSize.Value;
            %Convert value to an integer factor of 51
            Ratio = floor(value/51);
            NewValue = Ratio*51;
            app.StepSize.Value = NewValue;
        end

        % Button pushed function: NeedleNotVisibleButton
        function NeedleNotVisibleButtonPushed(app, event)
            global NeedleImg mmc
            %NeedleImg{1} = Snap2(mmc);
            mmc.waitForSystem();
            img = mmc.getLastImage();
            img = typecast(img,'uint16');
            width = mmc.getImageWidth();
            height = mmc.getImageHeight();
            img = imrotate(reshape(img,[width,height]),-90);
            NeedleImg{1} = img;
            mmc.waitForSystem();
            app.Img1Lamp.Color = [0 1 0];
        end

        % Button pushed function: NeedleVisibleButton
        function NeedleVisibleButtonPushed(app, event)
            global NeedleImg mmc
            mmc.waitForSystem()
            %mmc.snapImage();
            %NeedleImg{2} = Snap2(mmc);
            img = mmc.getLastImage();
            img = typecast(img,'uint16');
            width = mmc.getImageWidth();
            height = mmc.getImageHeight();
            img = imrotate(reshape(img,[width,height]),-90);
            NeedleImg{2} = img;
            mmc.waitForSystem();
            app.Img2Lamp.Color = [0 1 0];
            
            %Note: needle typically touches at apprximately 28,800 microns
            
        end

        % Button pushed function: CalculateNeedlePositionButton
        function CalculateNeedlePositionButtonPushed(app, event)
            global NeedleImg NeedlePos PDIR
            %PDIR = 'D:\MVX\Documents\Cody\MVX Experiment Data\Needle Calibration';
            DIR = PDIR;
            %Subtract image from image
            imgS = medfilt2(double(NeedleImg{1}) - double(NeedleImg{2}));
            %Binarize image; Otsu's method
            %thresh = multithresh(imgS,3);
            BW = imgS>max(imgS(:)-(1/7)*max(imgS(:)));
            %BW = imbinarize(imgS);
            %Remove small objects
            se2 = strel('disk',2);
            BW2 = imopen(BW,se2);
            %Fill in needle object -- if any holes exist
            se = strel('disk',15);
            BW3 = imclose(BW2,se);
            %Dilate point to create a disk shape --> improve finding centroid
            BW3 = imdilate(BW3,se);
            
            %label needle tip
            LabelNeedle = bwlabel(BW3);
            %Find the centroid of the needle
            NeedleCent = regionprops(LabelNeedle,'centroid');
            %Record centroid positions if only one centroid is found
            %Note that centroid position is from top left corner of image
            if length(NeedleCent) == 1
                %Record centroid x
                NeedlePos(1,1) = round(NeedleCent.Centroid(1));
                %Record centroid y
                NeedlePos(1,2) = round(NeedleCent.Centroid(2));
                %Turn on lamp to indicate
                app.XYCalibratedLamp.Color = [0 1 0];
                %save([DIR,'\NeedleImages.mat'],'NeedleImg','LabelNeedle')
                NeedlePos
                app.XManual.Value = NeedlePos(1,1);
                app.YManual.Value = NeedlePos(1,2);
            else
                f = msgbox('Centroid Not Found: Acquire new images');
                %Turn off image capture lamps to indicate that new images need to
                %be acquired
                app.Img1Lamp.Color = [1 0 0];
                app.Img2Lamp.Color = [1 0 0];
                app.XYCalibratedLamp.Color = [1 0 0];
            end
        end

        % Button pushed function: SetZHeightButton
        function SetZHeightButtonPushed(app, event)
            global NeedlePos
            %Bring needle tip to just touching the microraft array
            %Record z position of needle
            NeedlePos(1,3) = app.ZHeight.Value;
            %Turn on lamp to indicate
            app.ZCalibratedLamp.Color = [0 1 0];
            NeedlePos
            
        end

        % Value changed function: TestcodeButton
        function TestcodeButtonValueChanged(app, event)
            global NeedleImg mmc
            folder = 'D:\MVX\Documents\Cody\MVX Experiment Data\Needle Calibration'
            mmc.waitForSystem()
            img1 = mmc.getLastImage();
            mmc.waitForSystem();
            imwrite(uint16(img1), [folder,'\img1.tiff'])
            save([folder '\NeedleImages.mat'],'NeedleImg')
%             
        end

        % Value changed function: LimitsOverride
        function LimitsOverrideValueChanged(app, event)
            %Override the set limits of the motor; used to zero the actuator if not zeroed at start of program
            if app.LimitsOverride.Value == 1
                f =  msgbox({'CAUTION: YOU HAVE TURNED OFF ACTUATOR LIMITS';'COMPONENTS MAY COLLIDE'});
            else
                f =  msgbox({'ACTUATOR LIMITS HAVE BEEN ENABLED';'BE AWARE OF NEW ZERO AND LIMITS'});
            end
        end

        % Value changed function: UseManualSelectionsButton
        function UseManualSelectionsButtonValueChanged(app, event)
            global NeedlePos
            %Set the needle pixel location to the manually set values in GUI
            NeedlePos(1,1) = app.XManual.Value;
            NeedlePos(1,2) = app.YManual.Value;
            f =  msgbox({'CAUTION: YOU HAVE INPUT MANUAL VALUES FOR THE NEEDLE LOCATION'});
            %Turn the calibrated lamp on to indicate calibrated
            app.XYCalibratedLamp.Color = [0 1 0];
            
        end

        % Value changed function: ZeroButton
        function ZeroButtonValueChanged(app, event)
            global sm2 mmc
            %Needle Offset is the distance the needle is extended from the physical zero of the motor
            %NeedleZero retracts the needle fully,then extends the needle by the NeedleOffset distance
            NeedleExtension = 20043;
            % Operation of NeedleZero: Lower objective, retract needle fully, extend needle by offset, raise objective back to focus
            [FinalZ] = NeedleZero(NeedleExtension);
            %Update the Z height value of the needle to reflect the choen Needle Offset
            app.ZHeight.Value = FinalZ;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeedleCalibrationUIFigure = uifigure;
            app.NeedleCalibrationUIFigure.Name = 'Needle Calibration';
            app.NeedleCalibrationUIFigure.CloseRequestFcn = createCallbackFcn(app, @NeedleCalibrationUIFigureCloseRequest, true);
            app.SetZHeightButton = uibutton(app.NeedleCalibrationUIFigure, 'push');
            app.SetZHeightButton.ButtonPushedFcn = createCallbackFcn(app, @SetZHeightButtonPushed, true);
            app.SetZHeightButton.Text = 'Set Z Height';
            app.CalibratedButton = uibutton(app.NeedleCalibrationUIFigure, 'push');
            app.CalibratedButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratedButtonPushed, true);
            app.CalibratedButton.Text = 'Calibrated';
            app.NeedlePositioningPanel = uipanel(app.NeedleCalibrationUIFigure);
            app.NeedlePositioningPanel.Title = 'Needle Positioning';
            app.MoveUp = uibutton(app.NeedlePositioningPanel, 'push');
            app.MoveUp.ButtonPushedFcn = createCallbackFcn(app, @MoveUpPushed, true);
            app.MoveUp.Text = 'Move Up';
            app.MoveDown = uibutton(app.NeedlePositioningPanel, 'push');
            app.MoveDown.ButtonPushedFcn = createCallbackFcn(app, @MoveDownPushed, true);
            app.MoveDown.Text = 'Move Down';
            app.RountTrip = uibutton(app.NeedlePositioningPanel, 'push');
            app.RountTrip.ButtonPushedFcn = createCallbackFcn(app, @RountTripPushed, true);
            app.RountTrip.Text = 'Rount Trip';
            app.ZHeightumEditFieldLabel = uilabel(app.NeedlePositioningPanel);
            app.ZHeightumEditFieldLabel.Text = 'Z Height (um)';
            app.ZHeight = uieditfield(app.NeedlePositioningPanel, 'numeric');
            app.ZHeight.Limits = [0 31000];
            app.ZHeight.ValueDisplayFormat = '%.0f';
            app.ZHeight.Editable = 'off';
            app.StepSizeumEditFieldLabel = uilabel(app.NeedlePositioningPanel);
            app.StepSizeumEditFieldLabel.Text = 'Step Size (um)';
            app.StepSize = uieditfield(app.NeedlePositioningPanel, 'numeric');
            app.StepSize.Limits = [51 24000];
            app.StepSize.ValueChangedFcn = createCallbackFcn(app, @StepSizeValueChanged, true);
            app.StepSize.Value = 2550;
            app.LimitsOverride = uicheckbox(app.NeedlePositioningPanel);
            app.LimitsOverride.ValueChangedFcn = createCallbackFcn(app, @LimitsOverrideValueChanged, true);
            app.LimitsOverride.Text = 'Limits Override';
            app.ZeroButton = uibutton(app.NeedlePositioningPanel, 'state');
            app.ZeroButton.ValueChangedFcn = createCallbackFcn(app, @ZeroButtonValueChanged, true);
            app.ZeroButton.Text = 'Zero';
            app.ImageGuidedCalibrationPanel = uipanel(app.NeedleCalibrationUIFigure);
            app.ImageGuidedCalibrationPanel.Title = 'Image Guided Calibration';
            app.NeedleNotVisibleButton = uibutton(app.ImageGuidedCalibrationPanel, 'push');
            app.NeedleNotVisibleButton.ButtonPushedFcn = createCallbackFcn(app, @NeedleNotVisibleButtonPushed, true);
            app.NeedleNotVisibleButton.Text = 'Needle Not Visible';
            app.NeedleVisibleButton = uibutton(app.ImageGuidedCalibrationPanel, 'push');
            app.NeedleVisibleButton.ButtonPushedFcn = createCallbackFcn(app, @NeedleVisibleButtonPushed, true);
            app.NeedleVisibleButton.Text = 'Needle Visible';
            app.CalculateNeedlePositionButton = uibutton(app.ImageGuidedCalibrationPanel, 'push');
            app.CalculateNeedlePositionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNeedlePositionButtonPushed, true);
            app.CalculateNeedlePositionButton.Text = 'Calculate Needle Position';
            app.XYCalibratedLampLabel = uilabel(app.ImageGuidedCalibrationPanel);
            app.XYCalibratedLampLabel.Text = 'XY Calibrated';
            app.XYCalibratedLamp = uilamp(app.ImageGuidedCalibrationPanel);
            app.Img1Lamp = uilamp(app.ImageGuidedCalibrationPanel);
            app.Img2Lamp = uilamp(app.ImageGuidedCalibrationPanel);
            app.UseManualSelectionsButton = uibutton(app.ImageGuidedCalibrationPanel, 'state');
            app.UseManualSelectionsButton.ValueChangedFcn = createCallbackFcn(app, @UseManualSelectionsButtonValueChanged, true);
            app.UseManualSelectionsButton.Text = 'Use Manual Selections';
            app.XLabel = uilabel(app.ImageGuidedCalibrationPanel);
            app.XLabel.Text = 'X';
            app.XManual = uieditfield(app.ImageGuidedCalibrationPanel, 'numeric');
            app.YLabel = uilabel(app.ImageGuidedCalibrationPanel);
            app.YLabel.Text = 'Y';
            app.YManual = uieditfield(app.ImageGuidedCalibrationPanel, 'numeric');
            app.YManual.ValueDisplayFormat = '%.0f';
            app.ZCalibratedLamp = uilamp(app.NeedleCalibrationUIFigure);
            app.TestcodeButton = uibutton(app.NeedleCalibrationUIFigure, 'state');
            app.TestcodeButton.ValueChangedFcn = createCallbackFcn(app, @TestcodeButtonValueChanged, true);
            app.TestcodeButton.Text = 'Test code';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_CalibrateNeedle

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeedleCalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeedleCalibrationUIFigure)
        end
    end
end