classdef GUI_CalibrateWand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WandCalibrationUIFigure        matlab.ui.Figure
        SetCalibrationPositionButton   matlab.ui.control.Button
        CalibratedButton               matlab.ui.control.Button
        CalibrationPositionDropDownLabel  matlab.ui.control.Label
        CalibrationPosition            matlab.ui.control.DropDown
        LoadPreviousWPPositionsButton  matlab.ui.control.Button
        XYPlanePositioningPanel        matlab.ui.container.Panel
        YButtonPositive                matlab.ui.control.Button
        YButtonNegative                matlab.ui.control.Button
        XButtonNegative                matlab.ui.control.Button
        XPosumLabel                    matlab.ui.control.Label
        XPos                           matlab.ui.control.NumericEditField
        YPosumLabel                    matlab.ui.control.Label
        YPos                           matlab.ui.control.NumericEditField
        StepSizeumEditFieldLabel       matlab.ui.control.Label
        StepSize                       matlab.ui.control.NumericEditField
        XButtonPositive                matlab.ui.control.Button
        WandPositioningPanel           matlab.ui.container.Panel
        UpzButton                      matlab.ui.control.Button
        DownzButton                    matlab.ui.control.Button
        RountTripButton                matlab.ui.control.Button
        WandExtensionumLabel           matlab.ui.control.Label
        WandExtension                  matlab.ui.control.NumericEditField
        StepSizeumEditFieldLabel_2     matlab.ui.control.Label
        WandStepSize                   matlab.ui.control.NumericEditField
        ZeroButton                     matlab.ui.control.Button
        QuickMovePanel                 matlab.ui.container.Panel
        A1Button                       matlab.ui.control.Button
        A4Button                       matlab.ui.control.Button
        A2Button                       matlab.ui.control.Button
        A3Button                       matlab.ui.control.Button
        A5Button                       matlab.ui.control.Button
        A6Button                       matlab.ui.control.Button
        TLButton                       matlab.ui.control.Button
        TRButton                       matlab.ui.control.Button
        BLButton                       matlab.ui.control.Button
        BRButton                       matlab.ui.control.Button
        DemoButton                     matlab.ui.control.Button
        FOVButton                      matlab.ui.control.Button
        TestCodeButton                 matlab.ui.control.Button
        TLLampLabel                    matlab.ui.control.Label
        TLLamp                         matlab.ui.control.Lamp
        TRLampLabel                    matlab.ui.control.Label
        TRLamp                         matlab.ui.control.Lamp
        BLLampLabel                    matlab.ui.control.Label
        BLLamp                         matlab.ui.control.Lamp
        BRLampLabel                    matlab.ui.control.Label
        BRLamp                         matlab.ui.control.Lamp
        FOVLampLabel                   matlab.ui.control.Label
        FOVLamp                        matlab.ui.control.Lamp
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global s mmc LED sm1 shield1 ard WandPos
            
            %Turn on LED backlight
            powervalue=num2str(200);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
            %%%%%% %Initialize moog actuators
            
            %Create serial object
            s = serial('COM2');
            %Set a space as terminator
            s.Terminator = ' ';
            %open communication
            fopen(s);
            %read from device
            %out = fscanf(s);
            
            %Enable echo (required for two motors)
            fprintf(s,'ECHO ');
            %Disable Left limit
            fprintf(s,'EIGN(2) ');
            %Disable right limit
            fprintf(s,'EIGN(3) ');
            %Reset errors
            fprintf(s,'ZS ');
            %Set acceleration and velocity: units in microns
            fprintf(s,'ADT=100 ');
            fprintf(s,'VT=250000 ');
            %Setting the address of each motor --> Sets first motor and downstream
            %motors addresses
            %See Developers Guide pg. 825
            fprintf(s, 'ECHO a=1 WAIT=2000 PRINT(#128,"a=a+1",#13) WAIT=2000 ADDR=a WAIT=2000')
            %Set CAN address to motor address
            fprintf(s,'CADDR=ADDR ');
            
            %Move actuators to origin and set display values to zero
            MoogMove(0,0,s);
            app.XPos.Value  = 0;
            app.YPos.Value = 0;
            
            %%%%% Initialize Arduino to control wand actuator
            %Create arduino object with adafruit library
            ard = arduino('COM6','Uno','Libraries','Adafruit/MotorShieldV2');
            %Create an add-on shield object
            shield1 = addon(ard,'Adafruit/MotorShieldV2');
            %Create a stepper motor object; Port 2, 24 steps/rotation, 50 rpm, 2 coils
            %activated (double) for more torque
            sm1 = stepper(shield1,1,24,'RPM',50,'stepType','Double');
            %Set Speed
            sm1.RPM = 5; %8 is max; 2 is twice as slow
% %             %Zero the wand
% %             NeedleStep = 51;
% %             %Retract (+) the wand fully
% %             move(sm1,29988/NeedleStep);
% %             release(sm1);
% %             %Set the Wand Extension value to zero
% %             app.WandExtension.Value = 0;
            
            %Initialize a matrix to hold positions of well plate corners (4) and center of FOV
            WandPos = zeros(5,3);
            
        end

        % Button pushed function: YButtonPositive
        function YButtonPositivePushed(app, event)
            global s
            %Only move if within the limits of the actuators --> Y actuator limit of 210,000 um
            
            if (app.YPos.Value + app.StepSize.Value)<=210000
                xMove = (app.XPos.Value);
                yMove = (app.YPos.Value + app.StepSize.Value);
                MoogMove(xMove,yMove,s);
                app.YPos.Value = app.YPos.Value + app.StepSize.Value;
            end
            
        end

        % Button pushed function: YButtonNegative
        function YButtonNegativePushed(app, event)
            global s
            %Only move if within the limits of the actuators
            
            if (app.YPos.Value - app.StepSize.Value)>=0
                xMove = (app.XPos.Value);
                yMove = (app.YPos.Value - app.StepSize.Value);
                MoogMove(xMove,yMove,s);
                app.YPos.Value = app.YPos.Value - app.StepSize.Value;
            end
        end

        % Button pushed function: XButtonPositive
        function XButtonPositivePushed(app, event)
            global s
            %Only move if within the limits of the actuators --> X actuator limit of 300,000 um
            
            if (app.XPos.Value + app.StepSize.Value)<=300000
                xMove = (app.XPos.Value + app.StepSize.Value);
                yMove = app.YPos.Value;
                MoogMove(xMove,yMove,s);
                app.XPos.Value = app.XPos.Value + app.StepSize.Value;
            end
        end

        % Button pushed function: XButtonNegative
        function XButtonNegativePushed(app, event)
            global s
            %Only move if within the limits of the actuators
            if (app.XPos.Value - app.StepSize.Value)>=0
                xMove = (app.XPos.Value - app.StepSize.Value);
                yMove = app.YPos.Value;
                MoogMove(xMove,yMove,s);
                app.XPos.Value = app.XPos.Value - app.StepSize.Value;
            end
        end

        % Close request function: WandCalibrationUIFigure
        function WandCalibrationUIFigureCloseRequest(app, event)
            global himage TIMER mmc vid LED sm1 shield1 ard s
            

            %%%%% Disconnect from Arduino (Wand actuator)
            %% Move wand to origin before closing --> move to origin before moving moog actuators to avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value/WandStep) >0
                move(sm1,app.WandExtension.Value/WandStep);
                release(sm1);
            end
            
            %Clear arduino variables from global variable space
            sm1 = [];
            shield1 = [];
            ard = [];
            
            %Move actuators to origin
            MoogMove(0,0,s);
            %Turn of LED
            
            %Disconnect from Moog actuators
            fclose(s)
            delete(s)
            clear s

            %Close sequence acquisition
            mmc.stopSequenceAcquisition();
            stop(TIMER)
%             delete(timerfindall)
            delete(TIMER)

            close(vid)
            clear -global TIMER
            
            % turn off brightfield light
            powervalue=num2str(0);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
            delete(app)
            
        end

        % Button pushed function: LoadPreviousWPPositionsButton
        function LoadPreviousWPPositionsButtonPushed(app, event)
            %Load previous Well Plate positions
            global PDIR
            DIR = PDIR;
            
            if exist([DIR,'\data\WandPos.mat'])>0
                %Load the previous wand positions
                load([DIR,'\data\WandPos']);
                %Clear the FOV data from the previous wand positions file
                %WandPos(5,:) = 0;
                app.TLLamp.Color = [0 1 0];
                app.TRLamp.Color = [0 1 0];
                app.BLLamp.Color = [0 1 0];
                app.BRLamp.Color = [0 1 0];
                app.FOVLamp.Color = [0 1 0];
            end
            
        end

        % Button pushed function: SetCalibrationPositionButton
        function SetCalibrationPositionButtonPushed(app, event)
            global mmc WandPos StageWandPos
            %Set current position of wand (x,y,z) in matrix containing calibration positions
            %Row 1 = Well Plate TL; Row 2 = WP TR; Row 3 = WP BL; Row 4 = WP BR; Row 5 = Center of FOV
            %Calibration Position drop down determines row number (i)
            i = find(strcmp(app.CalibrationPosition.Items,app.CalibrationPosition.Value));
            WandPos(i,1) = app.XPos.Value;
            WandPos(i,2) = app.YPos.Value;
            WandPos(i,3) = app.WandExtension.Value;
            %Turn on lamps for each position calibrated
            switch i
                case 1
                    app.TLLamp.Color = [0 1 0];
                case 2
                    app.TRLamp.Color = [0 1 0];
                case 3
                    app.BLLamp.Color = [0 1 0];
                case 4
                    app.BRLamp.Color = [0 1 0];
                case 5
                    app.FOVLamp.Color = [0 1 0];
                    %Save the stage position when wand is in FOV: used to calculate wand positions in isolation code
                    StageWandPos(1,1) = mmc.getXPosition('XYStage');
                    StageWandPos(1,2) = mmc.getYPosition('XYStage');
            end
        end

        % Button pushed function: UpzButton
        function UpzButtonPushed(app, event)
            global sm1
            %% Retract wand (move upwards in z direction; away from microraft array)
            %Wand step size: 51 microns per step
            WandStep = 51;
            if (app.WandExtension.Value - app.WandStepSize.Value) >= 0
                move(sm1,app.WandStepSize.Value/WandStep);
                release(sm1);
                app.WandExtension.Value = app.WandExtension.Value - app.WandStepSize.Value;
            end
        end

        % Button pushed function: DownzButton
        function DownzButtonPushed(app, event)
            global sm1
            %% Extend wand (move downwards in z direction; towards microraft array)
            WandStep = 51;
            if (app.WandExtension.Value + app.WandStepSize.Value) <= 30000
                move(sm1,-app.WandStepSize.Value/WandStep);
                release(sm1);
                app.WandExtension.Value = app.WandExtension.Value + app.WandStepSize.Value;
            end
            
        end

        % Button pushed function: RountTripButton
        function RountTripButtonPushed(app, event)
            global sm1
            %% Extend and then retract wand equal distance
            tic
            WandStep = 51;
            if (app.WandExtension.Value + app.WandStepSize.Value) <= 30000
                move(sm1,-app.WandStepSize.Value/WandStep)
                move(sm1,app.WandStepSize.Value/WandStep);
                release(sm1);
            end
            toc
        end

        % Button pushed function: CalibratedButton
        function CalibratedButtonPushed(app, event)
            global himage TIMER mmc vid LED sm1 shield1 ard WandPos StageWandPos PDIR s
            
            DIR = PDIR;
            
            if any(WandPos(:)<-1)
                f = msgbox('Calibration Not Complete')
            else
                %% Move wand to origin before closing --> move to origin before moving moog actuators to avoid collisions
                WandStep = 51;
                if (app.WandExtension.Value/WandStep) > 0
                    move(sm1,app.WandExtension.Value/WandStep);
                    release(sm1);
                end
                %Move actuators to origin
                MoogMove(0,0,s);
                %Turn of LED
                
                %Disconnect from Moog actuators
                fclose(s)
                delete(s)
                clear s
                %%%%% Disconnect from Arduino (Wand actuator)
                
                %Clear arduino variables from global variable space
                sm1 = [];
                shield1 = [];
                ard = [];
                %Save Wand positions --> last step of calibrating the wand
                save([DIR,'\data\WandPos'],'WandPos','StageWandPos');
                
                %Close sequence acquisition
                mmc.stopSequenceAcquisition();
                stop(TIMER)
%             delete(timerfindall)
            delete(TIMER)
                close(vid)
                clear -global TIMER
                
                % turn off brightfield light
                powervalue=num2str(0);
                fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                pause(0.5)
                while LED.BytesAvailable>0
                    LEDMessage = fscanf(LED);
                end
                
                delete(app)
            end
            
        end

        % Button pushed function: A1Button
        function A1ButtonPushed(app, event)
            global s LED mmc sm1
            %% Move wand to origin before moving to new location; avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move actuators to nominal center of array 1
            %Set nominal position of array 1
            XP1 = 210000;
            YP1 = 21000;
            %Move to position within limits of actuators
            if (XP1>=0) && (XP1<=300000) && (YP1>=0) && (YP1<=210000)
                xMove = XP1;
                yMove = YP1;
                MoogMove(xMove,yMove,s);
            end
            app.XPos.Value = XP1;
            app.YPos.Value = YP1;
        end

        % Button pushed function: A2Button
        function A2ButtonPushed(app, event)
            global s LED mmc sm1
            %% Move wand to origin before moving to new location; avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move actuators to nominal center of array 1
            %Set nominal position of array 1
            XP1 = 210000;
            YP1 = 75000;
            %Move to position within limits of actuators
            if (XP1>=0) && (XP1<=300000) && (YP1>=0) && (YP1<=210000)
                xMove = XP1;
                yMove = YP1;
                MoogMove(xMove,yMove,s);
            end
            app.XPos.Value = XP1;
            app.YPos.Value = YP1;
        end

        % Button pushed function: A3Button
        function A3ButtonPushed(app, event)
            global s LED mmc sm1
            %% Move wand to origin before moving to new location; avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move actuators to nominal center of array 1
            %Set nominal position of array 1
            XP1 = 210000;
            YP1 = 147000;
            %Move to position within limits of actuators
            if (XP1>=0) && (XP1<=300000) && (YP1>=0) && (YP1<=210000)
                xMove = XP1;
                yMove = YP1;
                MoogMove(xMove,yMove,s);
            end
            app.XPos.Value = XP1;
            app.YPos.Value = YP1;
        end

        % Button pushed function: A4Button
        function A4ButtonPushed(app, event)
            global s LED mmc sm1
            %% Move wand to origin before moving to new location; avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move actuators to nominal center of array 4
            %Set nominal position of array 4
            XP1 = 144000;
            YP1 = 21000;
            %Move to position within limits of actuators
            if (XP1>=0) && (XP1<=300000) && (YP1>=0) && (YP1<=210000)
                xMove = XP1;
                yMove = YP1;
                MoogMove(xMove,yMove,s);
            end
            app.XPos.Value = XP1;
            app.YPos.Value = YP1;
        end

        % Button pushed function: A5Button
        function A5ButtonPushed(app, event)
            global s LED mmc sm1
            %% Move wand to origin before moving to new location; avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move actuators to nominal center of array 1
            %Set nominal position of array 1
            XP1 = 144000;
            YP1 = 75000;
            %Move to position within limits of actuators
            if (XP1>=0) && (XP1<=300000) && (YP1>=0) && (YP1<=210000)
                xMove = XP1;
                yMove = YP1;
                MoogMove(xMove,yMove,s);
            end
            app.XPos.Value = XP1;
            app.YPos.Value = YP1;
        end

        % Button pushed function: A6Button
        function A6ButtonPushed(app, event)
            global s LED mmc sm1
            %% Move wand to origin before moving to new location; avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move actuators to nominal center of array 1
            %Set nominal position of array 1
            XP1 = 144000;
            YP1 = 147000;
            %Move to position within limits of actuators
            if (XP1>=0) && (XP1<=300000) && (YP1>=0) && (YP1<=210000)
                xMove = XP1;
                yMove = YP1;
                MoogMove(xMove,yMove,s);
            end
            app.XPos.Value = XP1;
            app.YPos.Value = YP1;
        end

        % Value changed function: WandStepSize
        function WandStepSizeValueChanged(app, event)
            value = app.WandStepSize.Value;
            %Convert value to an integer factor of 51
            Ratio = floor(value/51);
            NewValue = Ratio*51;
            app.WandStepSize.Value = NewValue;
        end

        % Button pushed function: TestCodeButton
        function TestCodeButtonPushed(app, event)
            get (app.WandExtension)
        end

        % Button pushed function: TLButton
        function TLButtonPushed(app, event)
            global s LED mmc sm1
            %% Move wand to origin before moving to new location; avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move actuators to nominal center of array 1
            %Set nominal position of array 1
            XP1 = 64746;
            YP1 = 11499;
            %Move to position within limits of actuators
            if (XP1>=0) && (XP1<=300000) && (YP1>=0) && (YP1<=210000)
                xMove = XP1;
                yMove = YP1;
                MoogMove(xMove,yMove,s);
            end
            app.XPos.Value = XP1;
            app.YPos.Value = YP1;
        end

        % Button pushed function: TRButton
        function TRButtonPushed(app, event)
            global s LED mmc sm1
            %% Move wand to origin before moving to new location; avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move actuators to nominal center of array 1
            %Set nominal position of array 1
            XP1 = 504;
            YP1 = 9996;
            %Move to position within limits of actuators
            if (XP1>=0) && (XP1<=300000) && (YP1>=0) && (YP1<=210000)
                xMove = XP1;
                yMove = YP1;
                MoogMove(xMove,yMove,s);
            end
            app.XPos.Value = XP1;
            app.YPos.Value = YP1;
        end

        % Button pushed function: BLButton
        function BLButtonPushed(app, event)
            global s LED mmc sm1
            %% Move wand to origin before moving to new location; avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move actuators to nominal center of array 1
            %Set nominal position of array 1
            XP1 = 63504;
            YP1 = 109494;
            %Move to position within limits of actuators
            if (XP1>=0) && (XP1<=300000) && (YP1>=0) && (YP1<=210000)
                xMove = XP1;
                yMove = YP1;
                MoogMove(xMove,yMove,s);
            end
            app.XPos.Value = XP1;
            app.YPos.Value = YP1;
        end

        % Button pushed function: BRButton
        function BRButtonPushed(app, event)
            global s LED mmc sm1
            %% Move wand to origin before moving to new location; avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move actuators to nominal center of array 1
            %Set nominal position of array 1
            XP1 = 504;
            YP1 = 108996;
            %Move to position within limits of actuators
            if (XP1>=0) && (XP1<=300000) && (YP1>=0) && (YP1<=210000)
                xMove = XP1;
                yMove = YP1;
                MoogMove(xMove,yMove,s);
            end
            app.XPos.Value = XP1;
            app.YPos.Value = YP1;
        end

        % Value changed function: StepSize
        function StepSizeValueChanged(app, event)
            value = app.StepSize.Value;
            %Convert value to an integer factor of 3 --> Moog conversion factor is 3um/step --> need integer values for steps
            Ratio = floor(value/3);
            NewValue = Ratio*3;
            app.StepSize.Value = NewValue;
            
        end

        % Callback function
        function LimitsOverrideValueChanged(app, event)
            
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            global sm1
            %Zero the wand
            WandStep = 51;
            %Retract (+) the wand fully
            move(sm1,29988/WandStep);
            release(sm1);
            %Set the Wand Extension value to zero
            app.WandExtension.Value = 0;
            
            %             f =  msgbox({'CAUTION: YOU HAVE ZEROED THE NEEDLE ACTUATOR';'PHYSICAL LIMITS HAVE CHANGED'});
            %             app.WandExtension.Value = 0;
        end

        % Button pushed function: DemoButton
        function DemoButtonPushed(app, event)
            global mmc sm1 s LED
            %Move from array to each corner wells per array
            %Have well locations here
            %X and Y coords of Array 1, 2, 3, 4, 5, 6
            ArrayLocations = [210000 21000; 210000 75000; 210000 147000; 144000 21000; 144000 75000; 144000 147000];
            %TL TR BL BR
            WellPlateLocations = [72000 21000; 9000 21000; 72000 120000; 9000 120000];
            
            %Zero wand first
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move wand to array
            for array = 1:2
                %Move to each corner well for each array
                for i=1:4
                    %Move wand to above array
                    MX = ArrayLocations(array,1);
                    MY = ArrayLocations(array,2);
                    if MX >=0 && MX <=300000 && MY >=0 && MY<=210000
                        MoogMove(MX, MY,s)
                        pause(2.5)
                    else
                        'Move beyond Moog (xy) actuator limits';
                    end
                    %Extend and then retract wand
                    if (app.WandExtension.Value + app.WandStepSize.Value) < 25000
                        move(sm1,-app.WandStepSize.Value/WandStep)
                        move(sm1,app.WandStepSize.Value/WandStep);
                        release(sm1);
                    end
                    %Move wand to above corner well
                    MXWell = WellPlateLocations(i,1);
                    MYWell = WellPlateLocations(i,2);
                    if MX >=0 && MX <=300000 && MY >=0 && MY<=210000
                        MoogMove(MXWell, MYWell,s)
                        pause(2.5)
                    else
                        'Move beyond Moog (xy) actuator limits';
                    end
                    %Extend and then retract wand
                    WPExtension = 3000;
                    Ratio = floor(WPExtension/51);
                    WPExtension = Ratio*51;
                    if (app.WandExtension.Value + WPExtension) < 25000
                        move(sm1,-WPExtension/WandStep)
                        move(sm1,WPExtension/WandStep);
                        release(sm1);
                    end
                end
            end
            
            %Move actuators to origin
            MoogMove(0,0,s);
            
            
            % %
            % %             MX_temp = floor(WPXCords(r));
            % %             RatioX = floor(MX_temp/3);
            % %             MX = RatioX*3;
            % %
            % %             MY_temp = floor(WPYCords(c));
            % %             RatioY = floor(MY_temp/3);
            % %             MY = RatioY*3;
            % %
            
        end

        % Button pushed function: FOVButton
        function FOVButtonPushed(app, event)
            global s LED mmc sm1
            %% Move wand to origin before moving to new location; avoid collisions
            WandStep = 51;
            if (app.WandExtension.Value) >0
                move(sm1,app.WandExtension.Value/WandStep)
                app.WandExtension.Value = 0;
                release(sm1);
            end
            
            %Move actuators to nominal center of array 1
            %Set nominal position of array 1
            XP1 = 121983;
            YP1 = 62897;
            %Move to position within limits of actuators
            if (XP1>=0) && (XP1<=300000) && (YP1>=0) && (YP1<=210000)
                xMove = XP1;
                yMove = YP1;
                MoogMove(xMove,yMove,s);
            end
            app.XPos.Value = XP1;
            app.YPos.Value = YP1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WandCalibrationUIFigure = uifigure;
            app.WandCalibrationUIFigure.Name = 'Wand Calibration';
            app.WandCalibrationUIFigure.CloseRequestFcn = createCallbackFcn(app, @WandCalibrationUIFigureCloseRequest, true);
            app.SetCalibrationPositionButton = uibutton(app.WandCalibrationUIFigure, 'push');
            app.SetCalibrationPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SetCalibrationPositionButtonPushed, true);
            app.SetCalibrationPositionButton.Text = 'Set Calibration Position';
            app.CalibratedButton = uibutton(app.WandCalibrationUIFigure, 'push');
            app.CalibratedButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratedButtonPushed, true);
            app.CalibratedButton.Text = 'Calibrated';
            app.CalibrationPositionDropDownLabel = uilabel(app.WandCalibrationUIFigure);
            app.CalibrationPositionDropDownLabel.Text = 'Calibration Position';
            app.CalibrationPosition = uidropdown(app.WandCalibrationUIFigure);
            app.CalibrationPosition.Items = {'Well Plate TL Corner', 'Well Plate TR Corner', 'Well Plate BL Corner', 'Well Plate BR Corner', 'Center of FOV'};
            app.CalibrationPosition.Value = 'Center of FOV';
            app.LoadPreviousWPPositionsButton = uibutton(app.WandCalibrationUIFigure, 'push');
            app.LoadPreviousWPPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviousWPPositionsButtonPushed, true);
            app.LoadPreviousWPPositionsButton.Text = 'Load Previous WP Positions';
            app.XYPlanePositioningPanel = uipanel(app.WandCalibrationUIFigure);
            app.XYPlanePositioningPanel.Title = 'XY Plane Positioning';
            app.YButtonPositive = uibutton(app.XYPlanePositioningPanel, 'push');
            app.YButtonPositive.ButtonPushedFcn = createCallbackFcn(app, @YButtonPositivePushed, true);
            app.YButtonPositive.Text = 'Y (+)';
            app.YButtonNegative = uibutton(app.XYPlanePositioningPanel, 'push');
            app.YButtonNegative.ButtonPushedFcn = createCallbackFcn(app, @YButtonNegativePushed, true);
            app.YButtonNegative.Text = 'Y (-)';
            app.XButtonNegative = uibutton(app.XYPlanePositioningPanel, 'push');
            app.XButtonNegative.ButtonPushedFcn = createCallbackFcn(app, @XButtonNegativePushed, true);
            app.XButtonNegative.Text = 'X (-)';
            app.XPosumLabel = uilabel(app.XYPlanePositioningPanel);
            app.XPosumLabel.Text = 'X Pos (um)';
            app.XPos = uieditfield(app.XYPlanePositioningPanel, 'numeric');
            app.XPos.Limits = [0 300000];
            app.XPos.ValueDisplayFormat = '%.0f';
            app.XPos.Editable = 'off';
            app.YPosumLabel = uilabel(app.XYPlanePositioningPanel);
            app.YPosumLabel.Text = 'Y Pos (um)';
            app.YPos = uieditfield(app.XYPlanePositioningPanel, 'numeric');
            app.YPos.Limits = [0 210000];
            app.YPos.ValueDisplayFormat = '%.0f';
            app.YPos.Editable = 'off';
            app.StepSizeumEditFieldLabel = uilabel(app.XYPlanePositioningPanel);
            app.StepSizeumEditFieldLabel.Text = 'Step Size (um)';
            app.StepSize = uieditfield(app.XYPlanePositioningPanel, 'numeric');
            app.StepSize.Limits = [100 30000];
            app.StepSize.ValueDisplayFormat = '%.0f';
            app.StepSize.ValueChangedFcn = createCallbackFcn(app, @StepSizeValueChanged, true);
            app.StepSize.Value = 6000;
            app.XButtonPositive = uibutton(app.XYPlanePositioningPanel, 'push');
            app.XButtonPositive.ButtonPushedFcn = createCallbackFcn(app, @XButtonPositivePushed, true);
            app.XButtonPositive.Text = 'X (+)';
            app.WandPositioningPanel = uipanel(app.WandCalibrationUIFigure);
            app.WandPositioningPanel.Title = 'Wand Positioning';
            app.UpzButton = uibutton(app.WandPositioningPanel, 'push');
            app.UpzButton.ButtonPushedFcn = createCallbackFcn(app, @UpzButtonPushed, true);
            app.UpzButton.Text = 'Up (z)';
            app.DownzButton = uibutton(app.WandPositioningPanel, 'push');
            app.DownzButton.ButtonPushedFcn = createCallbackFcn(app, @DownzButtonPushed, true);
            app.DownzButton.Text = 'Down (z)';
            app.RountTripButton = uibutton(app.WandPositioningPanel, 'push');
            app.RountTripButton.ButtonPushedFcn = createCallbackFcn(app, @RountTripButtonPushed, true);
            app.RountTripButton.Text = 'Rount Trip';
            app.WandExtensionumLabel = uilabel(app.WandPositioningPanel);
            app.WandExtensionumLabel.Text = 'Wand Extension (um)';
            app.WandExtension = uieditfield(app.WandPositioningPanel, 'numeric');
            app.WandExtension.Limits = [0 30000];
            app.WandExtension.ValueDisplayFormat = '%.0f';
            app.WandExtension.Editable = 'off';
            app.StepSizeumEditFieldLabel_2 = uilabel(app.WandPositioningPanel);
            app.StepSizeumEditFieldLabel_2.Text = 'Step Size (um)';
            app.WandStepSize = uieditfield(app.WandPositioningPanel, 'numeric');
            app.WandStepSize.Limits = [51 25000];
            app.WandStepSize.ValueDisplayFormat = '%.0f';
            app.WandStepSize.ValueChangedFcn = createCallbackFcn(app, @WandStepSizeValueChanged, true);
            app.WandStepSize.Value = 5100;
            app.ZeroButton = uibutton(app.WandPositioningPanel, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.QuickMovePanel = uipanel(app.WandCalibrationUIFigure);
            app.QuickMovePanel.Title = 'Quick Move';
            app.A1Button = uibutton(app.QuickMovePanel, 'push');
            app.A1Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A1Button.Text = '1';
            app.A4Button = uibutton(app.QuickMovePanel, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4Button.Text = '4';
            app.A2Button = uibutton(app.QuickMovePanel, 'push');
            app.A2Button.ButtonPushedFcn = createCallbackFcn(app, @A2ButtonPushed, true);
            app.A2Button.Text = '2';
            app.A3Button = uibutton(app.QuickMovePanel, 'push');
            app.A3Button.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.A3Button.Text = '3';
            app.A5Button = uibutton(app.QuickMovePanel, 'push');
            app.A5Button.ButtonPushedFcn = createCallbackFcn(app, @A5ButtonPushed, true);
            app.A5Button.Text = '5';
            app.A6Button = uibutton(app.QuickMovePanel, 'push');
            app.A6Button.ButtonPushedFcn = createCallbackFcn(app, @A6ButtonPushed, true);
            app.A6Button.Text = '6';
            app.TLButton = uibutton(app.QuickMovePanel, 'push');
            app.TLButton.ButtonPushedFcn = createCallbackFcn(app, @TLButtonPushed, true);
            app.TLButton.Text = 'TL';
            app.TRButton = uibutton(app.QuickMovePanel, 'push');
            app.TRButton.ButtonPushedFcn = createCallbackFcn(app, @TRButtonPushed, true);
            app.TRButton.Text = 'TR';
            app.BLButton = uibutton(app.QuickMovePanel, 'push');
            app.BLButton.ButtonPushedFcn = createCallbackFcn(app, @BLButtonPushed, true);
            app.BLButton.Text = 'BL';
            app.BRButton = uibutton(app.QuickMovePanel, 'push');
            app.BRButton.ButtonPushedFcn = createCallbackFcn(app, @BRButtonPushed, true);
            app.BRButton.Text = 'BR';
            app.DemoButton = uibutton(app.QuickMovePanel, 'push');
            app.DemoButton.ButtonPushedFcn = createCallbackFcn(app, @DemoButtonPushed, true);
            app.DemoButton.Text = 'Demo';
            app.FOVButton = uibutton(app.QuickMovePanel, 'push');
            app.FOVButton.ButtonPushedFcn = createCallbackFcn(app, @FOVButtonPushed, true);
            app.FOVButton.Text = 'FOV';
            app.TestCodeButton = uibutton(app.WandCalibrationUIFigure, 'push');
            app.TestCodeButton.ButtonPushedFcn = createCallbackFcn(app, @TestCodeButtonPushed, true);
            app.TestCodeButton.Enable = 'off';
            app.TestCodeButton.Text = 'Test Code';
            app.TLLampLabel = uilabel(app.WandCalibrationUIFigure);
            app.TLLampLabel.Text = 'TL';
            app.TLLamp = uilamp(app.WandCalibrationUIFigure);
            app.TRLampLabel = uilabel(app.WandCalibrationUIFigure);
            app.TRLampLabel.Text = 'TR';
            app.TRLamp = uilamp(app.WandCalibrationUIFigure);
            app.BLLampLabel = uilabel(app.WandCalibrationUIFigure);
            app.BLLampLabel.Text = 'BL';
            app.BLLamp = uilamp(app.WandCalibrationUIFigure);
            app.BRLampLabel = uilabel(app.WandCalibrationUIFigure);
            app.BRLampLabel.Text = 'BR';
            app.BRLamp = uilamp(app.WandCalibrationUIFigure);
            app.FOVLampLabel = uilabel(app.WandCalibrationUIFigure);
            app.FOVLampLabel.Text = 'FOV';
            app.FOVLamp = uilamp(app.WandCalibrationUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_CalibrateWand

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WandCalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WandCalibrationUIFigure)
        end
    end
end