classdef GUI_IsolateMicrorafts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        IsolationParametersPanel      matlab.ui.container.Panel
        BeginIsolationButton          matlab.ui.control.StateButton
        ReleaseRafts                  matlab.ui.control.CheckBox
        CollectRafts                  matlab.ui.control.CheckBox
        SaveVideo                     matlab.ui.control.CheckBox
        ArrayDataLabel                matlab.ui.control.Label
        Array                         matlab.ui.control.DropDown
        CollectionPlatePanel          matlab.ui.container.Panel
        CollectionPlateDropDownLabel  matlab.ui.control.Label
        CollectionPlate               matlab.ui.control.DropDown
        StartingRowDropDownLabel      matlab.ui.control.Label
        StartingRow                   matlab.ui.control.DropDown
        EndRowDropDownLabel           matlab.ui.control.Label
        EndRow                        matlab.ui.control.DropDown
        StartingColumnDropDownLabel   matlab.ui.control.Label
        StartingColumn                matlab.ui.control.DropDown
        EndColumnDropDownLabel        matlab.ui.control.Label
        EndColumn                     matlab.ui.control.DropDown
        OpticalSetupPanel             matlab.ui.container.Panel
        ObjectiveDropDownLabel        matlab.ui.control.Label
        Objective                     matlab.ui.control.DropDown
        MagnificationDropDownLabel    matlab.ui.control.Label
        Magnification                 matlab.ui.control.DropDown
        ArrayTypeDropDownLabel        matlab.ui.control.Label
        ArrayType                     matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global mmc LED sm1 shield ard sm2 NeedlePos s
            
            %%%%%%%%%%%%%%% Initialize Arduino to control Needle actuator %%%%%%%%%%%%%%%%%%%%%%%%
            %Create arduino object with adafruit library
            ard = arduino('COM6','Uno','Libraries','Adafruit/MotorShieldV2');
            %Create an add-on shield object
            shield = addon(ard,'Adafruit/MotorShieldV2');
            
            %Create a stepper motor object; Needle
            %activated (double) for more torque
            sm2 = stepper(shield,2,24,'RPM',8,'stepType','Double');
            %Set Speed
            sm2.RPM = 4;
            
            %Create a stepper motor object; Wand
            %activated (double) for more torque
            sm1 = stepper(shield,1,24,'RPM',8,'stepType','Double');
            %Set Speed
            sm1.RPM = 2;  %8 is max speed; 2 is twice as slow
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%%%% Initialize moog actuators %%%%%%%%%%%%%%%%%%%%
            %Create serial object
            s = serial('COM2');
            %Set a space as terminator
            s.Terminator = ' ';
            %open communication
            fopen(s);
            %read from device
            %out = fscanf(s);
            
            %Enable echo (required for two motors)
            fprintf(s,'ECHO ');
            %Disable Left limit
            fprintf(s,'EIGN(2) ');
            %Disable right limit
            fprintf(s,'EIGN(3) ');
            %Reset errors
            fprintf(s,'ZS ');
            %Set acceleration and velocity: units in microns
            fprintf(s,'ADT=100 ');
            fprintf(s,'VT=250000 ');
            %Setting the address of each motor --> Sets first motor and downstream
            %motors addresses
            %See Developers Guide pg. 825
            fprintf(s, 'ECHO a=1 WAIT=2000 PRINT(#128,"a=a+1",#13) WAIT=2000 ADDR=a WAIT=2000')
            %Set CAN address to motor address
            fprintf(s,'CADDR=ADDR ');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %             %Turn on the LED
            %             %Turn on LED backlight
            %             powervalue=num2str(120); % Was 350 last
            %             fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            %             pause(0.5)
            %             while LED.BytesAvailable>0
            %                 LEDMessage = fscanf(LED);
            %             end
            
            %Set exposure and backlight power based on array type
            arrayType = find(strcmp(app.ArrayType.Value,app.ArrayType.Items));
            switch arrayType
                case 1
                    exposureValue = 40; % Remove aperture when using standard arrays
                    ledPower = 330;
                case 2
                    exposureValue = 10; % Use 10 if removing aperture; use 40 if keeping aperture
                    ledPower = 330;
                case 3
                    exposureValue = 10;
                    ledPower = 500;
            end
            mmc.setExposure(exposureValue)
            
            %Turn on LED backlight
            powervalue=num2str(ledPower); % Was 350 last
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global LED sm1 shield ard sm2 NeedlePos s
            %Move actuators to origin
            MoogMove(0,0,s);
            %Turn of LED
            
            %Turn off LED backlight
            powervalue=num2str(0);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
            %Clear arduino variables from global variable space --> Release Device
            sm1 = [];
            sm2 = [];
            shield = [];
            ard = [];
            
            %Disconnect from Moog actuators
            fclose(s)
            delete(s)
            clear s
            
            delete(app)
            
        end

        % Value changed function: BeginIsolationButton
        function BeginIsolationButtonValueChanged(app, event)
            global mmc LED sm1 sm2 PDIR s
            DIR = PDIR;
            
            Rel_Loc = [];
            
            load([DIR,'\data\Registration'],'Raft_locations_cell','Plane_cell','Positions','fittp_cell');
            %load([DIR,'\data\Array_Dim']);
%             load([DIR,'\Rel_Loc'],'Rel_Loc','array_select');
            load([DIR,'\data\WandPos'],'WandPos','StageWandPos');
            load([DIR,'\data\NeedlePos']);
            
            %Clear pixel size loaded from Registration
            pixel_size = [];
            %Find pixel size for current video feed
            objective = find(strcmpi(app.Objective.Value,app.Objective.Items));
            switch objective
                case 1
                    %Pixel_Sizes = [10.6578 8.4321 4.5915 3.8648 3.2366 3.1675 2.6194 2.0458 1.6283 1.2945 1.0439];
                    Pixel_Sizes = [10.6578 8.4321 4.5915 3.8648 3.2366 3.1675 2.636 2.0568 1.6353 1.2981 1.0446];
                    MagArray = [0.63,0.8,1,1.25,1.6,2,2.5,3.2,4,5,6.3];
                case 2
                    %Pixel_Sizes = [5.1587 4.0625 3.2244 2.6037 2.0307 1.6308 1.3077 1.0262 0.8176 0.6505 0.5228];
                    Pixel_Sizes = [5.1587 4.0625 3.2244 2.6558 2.068 1.6535 1.3163 1.0257 0.8203 0.6522 0.5247];
                    MagArray = 2*[0.63,0.8,1,1.25,1.6,2,2.5,3.2,4,5,6.3];
            end
            Mag = find(strcmpi(app.Magnification.Value,app.Magnification.Items));
            pixel_size = Pixel_Sizes(Mag);
            %Find theoretical magnification for AFocusIsolation program
            MagFocus = MagArray(Mag);
            
            %Set exposure and backlight power based on array type
            arrayType = find(strcmp(app.ArrayType.Value,app.ArrayType.Items));
            switch arrayType
                case 1
                    exposureValue = 10; % Remove aperture when using standard arrays
                    ledPower = 330;
                case 2
                    exposureValue = 10; % Use 10 w/o aperture; use 40 with aperture
                    ledPower = 330; % Use 3330 w/o aperture; use 500 with aperture
                case 3
                    exposureValue = 40;
                    ledPower = 500;
            end
            mmc.setExposure(exposureValue)
            powervalue=num2str(ledPower); % Was 350 last
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
            %Find rows and columns for well plate, find # of rows and columns
            c1 = find(strcmp(app.StartingColumn.Value,app.StartingColumn.Items));
            c2 = find(strcmp(app.EndColumn.Value,app.EndColumn.Items));
            r1 = find(strcmp(app.StartingRow.Value,app.StartingRow.Items));
            r2 = find(strcmp(app.EndRow.Value,app.EndRow.Items));
            
            %Determine number of rows and columns based on collection plate size
            plate = find(strcmp(app.CollectionPlate.Value,app.CollectionPlate.Items));
            if plate == 1
                maxRows = 8;
                maxColumns = 12;
            else
                maxRows = 24;
                maxColumns = 16;
            end
            % Define locations of wells
            maxX = min(WandPos(1,1),WandPos(3,1));
            minX = max(WandPos(2,1),WandPos(4,1));
            maxY = min(WandPos(3,2),WandPos(4,2));
            minY = max(WandPos(1,2),WandPos(2,2));
            %Find coordinates of all wells in well plate --> Well Plate (WP) x coordinates and y coordinates
            WPXCords = linspace(minX,maxX,maxRows);
            WPYCords = linspace(minY,maxY,maxColumns);
            
            %%%%%%%%%%%%%%%%%%%  Create variables for testing code %%%%%%%%%%%%%%%%%%%%%
            Array_Dim = [100 50 175];
            
%             Rel_Loc = Rel_Loc(9:48,1:2);
            Rel_Loc = [];
            array_select = [1];
            Rel_Loc = [12439,1;10815,1;9558,1;9490,1;1367,1;1632,1;14450,1;1144,1];
            
            
            %             Rel_Loc = [10052,1;15457,1;13301,1;5070,1;13481,1;11121,1;5245,1;4756,1];
            
            
            %             Rel_Loc = [3704,1;13461,1;3597,1;7223,1;12263,1;15293,1;1591,1;18216,1;15204,1;9542,1;8543,1;8757,1];
            %             Rel_Loc = [14793,1;5412,1;13324,1;12841,1];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Create a variable to pass the GUI begin isolation button to the function --> used to abort if desired
            appButton = app.BeginIsolationButton();
            
            %Pass to isolate function collecting, releasing, and video saving preferences
            releaseflag = app.ReleaseRafts.Value;
            collectflag = app.CollectRafts.Value;
            releasevideo = app.SaveVideo.Value;
            %Warn user to remove lids
            warning1 = warndlg('Make sure  array and well plate lids are removed and magnification is correct!');
            uiwait(warning1)
            %Run isolation function
            if app.BeginIsolationButton.Value == 1
                app.BeginIsolationButton.Text='Abort';
                tic
                NeedleRelease(DIR,Rel_Loc,Raft_locations_cell,Plane_cell,fittp_cell,Positions,Array_Dim,pixel_size,array_select,WandPos,StageWandPos,NeedlePos,c1,c2,r1,r2,WPXCords,WPYCords,MagFocus,appButton,releaseflag,collectflag,releasevideo,arrayType)
                app.BeginIsolationButton.Text='Complete';
                toc
            else
                app.BeginIsolationButton.Text='Aborted';
            end
        end

        % Value changed function: StartingColumn
        function StartingColumnValueChanged(app, event)
            %Find starting and ending column values
            c1 = find(strcmp(app.StartingColumn.Value,app.StartingColumn.Items));
            c2 = find(strcmp(app.EndColumn.Value,app.EndColumn.Items));
            
            %Update the end column if the new starting column is larger
            if (c1 > c2) && (c1<length(app.StartingColumn.Items))
                f = msgbox('Please update end column value');
                app.EndColumn.Value = num2cell(num2str(c1),2);
                %If the last column, update the end column to match
            elseif c1==length(app.StartingColumn.Items)
                app.EndColumn.Value = num2cell(num2str(c1),2);
            end
        end

        % Value changed function: CollectionPlate
        function CollectionPlateValueChanged(app, event)
            plate = find(strcmp(app.CollectionPlate.Value,app.CollectionPlate.Items));
            if plate == 1
                %Update options for a 96-well plate
                app.StartingColumn.Items = {'1' '2' '3' '4' '5' '6' '7' '8' '9' '10' '11' '12'};
                app.EndColumn.Items = {'1' '2' '3' '4' '5' '6' '7' '8' '9' '10' '11' '12'};
                app.StartingRow.Items = {'A' 'B' 'C' 'D' 'E' 'F' 'G' 'H'};
                app.EndRow.Items = {'A' 'B' 'C' 'D' 'E' 'F' 'G' 'H'};
            else
                %Update options for a 384-well plate
                app.StartingColumn.Items = {'1' '2' '3' '4' '5' '6' '7' '8' '9' '10' '11' '12' '13' '14' '15' '16' '17' '18' '19' '20' '21' '22' '23' '24'};
                app.EndColumn.Items = {'1' '2' '3' '4' '5' '6' '7' '8' '9' '10' '11' '12' '13' '14' '15' '16' '17' '18' '19' '20' '21' '22' '23' '24'};
                app.StartingRow.Items = {'A' 'B' 'C' 'D' 'E' 'F' 'G' 'H' 'I' 'J' 'K' 'L' 'M' 'N' 'O' 'P'};
                app.EndRow.Items = {'A' 'B' 'C' 'D' 'E' 'F' 'G' 'H' 'I' 'J' 'K' 'L' 'M' 'N' 'O' 'P'};
            end
        end

        % Value changed function: EndColumn
        function EndColumnValueChanged(app, event)
            %Find starting and ending column values
            c1 = find(strcmp(app.StartingColumn.Value,app.StartingColumn.Items));
            c2 = find(strcmp(app.EndColumn.Value,app.EndColumn.Items));
            
            %Update the starting column if the new starting column is less
            if (c1 > c2)
                f = msgbox('Please update starting column value');
                app.StartingColumn.Value = num2cell(num2str(c2),2);
            end
        end

        % Value changed function: StartingRow
        function StartingRowValueChanged(app, event)
            %Find starting and ending column values
            r1 = find(strcmp(app.StartingRow.Value,app.StartingRow.Items));
            r2 = find(strcmp(app.EndRow.Value,app.EndRow.Items));
            
            %Update the end row if the new starting row is larger
            if (r1 > r2) && (r1<length(app.StartingRow.Items))
                f = msgbox('Please update end row value');
                app.EndRow.Value = app.EndRow.Items{r1};
                %If the last row, update the end row to match
            elseif r1==length(app.StartingRow.Items)
                app.EndRow.Value = app.EndRow.Items{r1};
            end
        end

        % Value changed function: EndRow
        function EndRowValueChanged(app, event)
            %Find starting and ending column values
            r1 = find(strcmp(app.StartingRow.Value,app.StartingRow.Items))
            r2 = find(strcmp(app.EndRow.Value,app.EndRow.Items))
            
            %Update the end row if the new starting row is less
            if (r1 > r2)
                f = msgbox('Please update starting row value');
                app.StartingRow.Value = app.EndRow.Items{r2};
            end
        end

        % Callback function
        function NeedleReleaseButtonPushed(app, event)
            
        end

        % Value changed function: ArrayType
        function ArrayTypeValueChanged(app, event)
            global mmc LED
            %Set exposure and backlight power based on array type
            arrayType = find(strcmp(app.ArrayType.Value,app.ArrayType.Items));
            switch arrayType
                case 1
                    exposureValue = 40; % Remove aperture when using standard arrays
                    ledPower = 330;
                case 2
                    exposureValue = 10; % Use 10 if removing aperture; use 40 if keeping aperture
                    ledPower = 330;
                case 3
                    exposureValue = 40;
                    ledPower = 500;
            end
            mmc.setExposure(exposureValue)
            powervalue=num2str(ledPower); % Was 350 last
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IsolationParametersPanel = uipanel(app.UIFigure);
            app.IsolationParametersPanel.Title = 'Isolation Parameters';
            app.BeginIsolationButton = uibutton(app.IsolationParametersPanel, 'state');
            app.BeginIsolationButton.ValueChangedFcn = createCallbackFcn(app, @BeginIsolationButtonValueChanged, true);
            app.BeginIsolationButton.Text = 'Begin Isolation';
            app.ReleaseRafts = uicheckbox(app.IsolationParametersPanel);
            app.ReleaseRafts.Text = 'Release Rafts';
            app.ReleaseRafts.Value = true;
            app.CollectRafts = uicheckbox(app.IsolationParametersPanel);
            app.CollectRafts.Text = 'Collect Rafts';
            app.CollectRafts.Value = true;
            app.SaveVideo = uicheckbox(app.IsolationParametersPanel);
            app.SaveVideo.Text = 'Save Video';
            app.ArrayDataLabel = uilabel(app.IsolationParametersPanel);
            app.ArrayDataLabel.Text = 'Array Data';
            app.Array = uidropdown(app.IsolationParametersPanel);
            app.Array.Items = {'Combined Data', '1', '2', '3', '4', '5', '6'};
            app.Array.Value = 'Combined Data';
            app.CollectionPlatePanel = uipanel(app.UIFigure);
            app.CollectionPlatePanel.Title = 'Collection Plate';
            app.CollectionPlateDropDownLabel = uilabel(app.CollectionPlatePanel);
            app.CollectionPlateDropDownLabel.Text = 'Collection Plate';
            app.CollectionPlate = uidropdown(app.CollectionPlatePanel);
            app.CollectionPlate.Items = {'96-Well Plate', '384-Well Plate'};
            app.CollectionPlate.ValueChangedFcn = createCallbackFcn(app, @CollectionPlateValueChanged, true);
            app.CollectionPlate.Value = '96-Well Plate';
            app.StartingRowDropDownLabel = uilabel(app.CollectionPlatePanel);
            app.StartingRowDropDownLabel.Text = 'Starting Row';
            app.StartingRow = uidropdown(app.CollectionPlatePanel);
            app.StartingRow.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H'};
            app.StartingRow.Editable = 'on';
            app.StartingRow.ValueChangedFcn = createCallbackFcn(app, @StartingRowValueChanged, true);
            app.StartingRow.Value = 'A';
            app.EndRowDropDownLabel = uilabel(app.CollectionPlatePanel);
            app.EndRowDropDownLabel.Text = 'End Row';
            app.EndRow = uidropdown(app.CollectionPlatePanel);
            app.EndRow.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H'};
            app.EndRow.Editable = 'on';
            app.EndRow.ValueChangedFcn = createCallbackFcn(app, @EndRowValueChanged, true);
            app.EndRow.Value = 'H';
            app.StartingColumnDropDownLabel = uilabel(app.CollectionPlatePanel);
            app.StartingColumnDropDownLabel.Text = 'Starting Column';
            app.StartingColumn = uidropdown(app.CollectionPlatePanel);
            app.StartingColumn.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.StartingColumn.Editable = 'on';
            app.StartingColumn.ValueChangedFcn = createCallbackFcn(app, @StartingColumnValueChanged, true);
            app.StartingColumn.Value = '1';
            app.EndColumnDropDownLabel = uilabel(app.CollectionPlatePanel);
            app.EndColumnDropDownLabel.Text = 'End Column';
            app.EndColumn = uidropdown(app.CollectionPlatePanel);
            app.EndColumn.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.EndColumn.Editable = 'on';
            app.EndColumn.ValueChangedFcn = createCallbackFcn(app, @EndColumnValueChanged, true);
            app.EndColumn.Value = '12';
            app.OpticalSetupPanel = uipanel(app.UIFigure);
            app.OpticalSetupPanel.Title = 'Optical Setup';
            app.ObjectiveDropDownLabel = uilabel(app.OpticalSetupPanel);
            app.ObjectiveDropDownLabel.Text = 'Objective';
            app.Objective = uidropdown(app.OpticalSetupPanel);
            app.Objective.Items = {'1x', '2x'};
            app.Objective.Value = '2x';
            app.MagnificationDropDownLabel = uilabel(app.OpticalSetupPanel);
            app.MagnificationDropDownLabel.Text = 'Magnification';
            app.Magnification = uidropdown(app.OpticalSetupPanel);
            app.Magnification.Items = {'0.63', '0.8', '1', '1.25', '1.6', '2', '2.5', '3.2', '4', '5', '6.3'};
            app.Magnification.Value = '6.3';
            app.ArrayTypeDropDownLabel = uilabel(app.UIFigure);
            app.ArrayTypeDropDownLabel.Text = 'Array Type';
            app.ArrayType = uidropdown(app.UIFigure);
            app.ArrayType.Items = {'Standard', 'Clear 1', 'Clear 2'};
            app.ArrayType.ValueChangedFcn = createCallbackFcn(app, @ArrayTypeValueChanged, true);
            app.ArrayType.Value = 'Clear 1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_IsolateMicrorafts

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end