classdef GUI_LiveCheck < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LiveScreen          matlab.ui.Figure
        NextButton          matlab.ui.control.Button
        BFAxes              matlab.ui.control.UIAxes
        FinishButton        matlab.ui.control.Button
        RaftEditFieldLabel  matlab.ui.control.Label
        RaftDisplay         matlab.ui.control.NumericEditField
        BlueAxes            matlab.ui.control.UIAxes
        RedAxes             matlab.ui.control.UIAxes
        GreenAxes           matlab.ui.control.UIAxes
        SaveImages          matlab.ui.control.CheckBox
    end

    methods (Access = private)
        
        function displayRaft(app, DIR, array, timepoint, raft, L_array, Loc_cell2, PFilters)
            
            %Pull out data for raft location (RL) --> array, row, column, local address,
            % global address
            RL = Loc_cell2{array,timepoint}(raft,:);
            row = RL(:,2);
            column = RL(:,3);
            LA = RL(:,4);
            %Find labeled array using rows and columns
            L = L_array{row, column};
            %Create bounding box for microraft
            ZL = regionprops(L,'Centroid','BoundingBox');
            BB = [ZL(LA).BoundingBox];
            TIME = size(Loc_cell2,2)-1;
            CropImage = cell(6,1);
            Filters = [PFilters 1];
            parfor z = 1:6
                if Filters(z)==1
                    CropImage2 = cell(1,TIME+1);
                    for TT = 0:TIME
                        STRING = {'Blue','Violet','Green','Red','FarRed','BF'};
                        img=imread([DIR,'\',num2str(array),'\', num2str(row),'x', num2str(column),'\', num2str(TT,'%02.0f'),'_',STRING{z},'.tif']);
                        if z ~= 6
                            CropImage2{TT+1} = imadjust(imcrop(img,BB), [0.01, 0.3],[]);
                        else
                            CropImage2{TT+1} = imcrop(img,BB);
                        end
                    end
                    CropImage{z} = cell2mat(CropImage2);
                end
            end
            for z = [find(PFilters),6]
                switch z
                    case 1
                        imshow(CropImage{z},[],'Parent',app.BlueAxes);
                    case 2
                        imshow(CropImage{z},[],'Parent',app.BlueAxes);
                    case 3
                        imshow(CropImage{z},[],'Parent',app.GreenAxes);
                    case 4
                        imshow(CropImage{z},[],'Parent',app.RedAxes);
                    case 5
                        imshow(CropImage{z},[],'Parent',app.RedAxes);
                    case 6
                        imshow(CropImage{z},[],'Parent',app.BFAxes);
                end
            end
            
        end
        
        function moveStage(app, raftNum, array, DIR)
            global mmc
            
            %%%%%%%%%%  Move stage to location of microraft %%%%%%%%%%%%%%
            load([DIR,'\data\Registration.mat'])
            %             pixel_size = 0.5247; % For 12.6x magnification
            micron_loc = Raft_locations_cell{array}(raftNum,:);
            %             newposx = round(micron_loc(1) - (1024*pixel_size));
            %             newposy = round(micron_loc(2) - (1024*pixel_size));
            newposx = round(micron_loc(1));
            newposy = round(micron_loc(2));
            
            mmc.setXYPosition('XYStage',newposx,newposy);
            
            Plane = Plane_cell{array,1};
            fittp = fit([Plane(:,1) Plane(:,2)], Plane(:,3), 'thinplateinterp');
            zn = fittp(newposx,newposy);
            mmc.setPosition('ZStage',zn); % offset helps with rafts partially released
            mmc.waitForSystem
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Load directory and release data to get address of rafts to release
            global PDIR PFilters
            DIR = PDIR;
            
            load([DIR,'\Rel_Loc.mat']); %loads Release_Locations
            
            raft = Rel_Loc(1,1);
            timepoint = 1;
            array = Rel_Loc(1,2);
            
            if exist([DIR,'\data\Loc_cell2.mat'],'file')==1
                load([DIR,'\data\Loc_cell2.mat']);
                load([DIR,'\data\L_cell\A',num2str(Rel_Loc(1,2)),'_T1.mat']);
                parpool;
                displayRaft(app, DIR, array, timepoint, raft, L_array, Loc_cell2, PFilters) 
            end
             
            %Set raft display value to 1, to indicate the first image is being viewed
            app.RaftDisplay.Value = 1;
            
            %%%%%%%%%%  Move stage to location of microraft %%%%%%%%%%%%%%
            %Warn user to remove lids
            warning1 = warndlg('MAKE SURE STAGE IS READY TO BE MOVED BEFORE PROCEEDING');
            uiwait(warning1)
            raftNum = Rel_Loc(1, 1); % Load the first row and second column of release locations data for first raft
            moveStage(app,raftNum, array, DIR)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%% Make a directory for saving images of the rafts kept for isolation
            mkdir([DIR,'\Live Cell Verification'])
            
            
        end

        % Close request function: LiveScreen
        function LiveScreenCloseRequest(app, event)
            global TIMER mmc vid LED
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Close sequence acquisition
            mmc.stopSequenceAcquisition();
            stop(TIMER)
            delete(TIMER)
            close(vid)
            clear -global TIMER
            
            % turn off brightfield light
            powervalue=num2str(0);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            delete(gcp('nocreate'))
            delete(app)
            
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            global PDIR PFilters mmc
            DIR = PDIR;
            load([DIR,'\Rel_Loc.mat']); %loads Rel_Loc
            
            if exist([DIR,'\data\Loc_cell2.mat'],'file')==1
                load([DIR,'\data\Loc_cell2.mat']);
                load([DIR,'\data\L_cell\A',num2str(Rel_Loc(RaftCount,2)),'_T1.mat']);
            end
            
            %Save raft location for release
            RaftCount = app.RaftDisplay.Value;  
            numLimit = size(Rel_Loc,1);

            %Save an image of raft/cells
            if app.SaveImages.Value == 1
                mmc.waitForSystem();
                img = mmc.getLastImage();
                img = typecast(img,'uint16');
                width = mmc.getImageWidth();
                height = mmc.getImageHeight();
                img = imrotate(reshape(img,[width,height]),-90);
                winSize = 150; % 120 pixels  ~= 60 microns at 12.6x total mag
                img = imcrop(img, [1024-winSize 1024-winSize winSize*2 winSize*2]);
                mmc.waitForSystem();
                imwrite(uint16(img), [DIR,'\Live Cell Verification','\Raft ',num2str(Rel_Loc(RaftCount,1)),'.png'])
            end
            
            %%%%%%%%% Code for combined data --> load next set of images
            
            %Only update images, raft number, etc. if there are more rafts for the array selected
            if RaftCount < numLimit
                RaftCount = app.RaftDisplay.Value + 1;
                raft = Rel_Loc(RaftCount,1);
                timepoint = 1;
                array = Rel_Loc(RaftCount,2);
                
                if exist([DIR,'\data\Loc_cell2.mat'],'file')==1
                    load([DIR,'\data\Loc_cell2.mat']);
                    load([DIR,'\data\L_cell\A',num2str(Rel_Loc(RaftCount,2)),'_T1.mat']);
                    displayRaft(app, DIR, array, timepoint, raft, L_array, Loc_cell2, PFilters)
                end
                %Update raft display value
                app.RaftDisplay.Value = RaftCount;
                
                %%%%%%%%%%  Move stage to location of microraft %%%%%%%%%%%%%%
                raftNum = Rel_Loc(RaftCount, 1);
                moveStage(app, raftNum, array, DIR)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else
                f = msgbox('You have reached the end of the rafts selected for release');
            end
        end

        % Callback function
        function DiscardButtonPushed(app, event)
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            global PDIR TIMER mmc vid LED
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Close sequence acquisition
            mmc.stopSequenceAcquisition();
            stop(TIMER)
            delete(TIMER)
            close(vid)
            clear -global TIMER
            
            % turn off brightfield light
            powervalue=num2str(0);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Close app and return to main app, close parallel pool
            app.delete
            delete(gcp('nocreate'))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LiveScreen = uifigure;
            app.LiveScreen.Name = 'UI Figure';
            app.LiveScreen.CloseRequestFcn = createCallbackFcn(app, @LiveScreenCloseRequest, true);
            app.NextButton = uibutton(app.LiveScreen, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BFAxes = uiaxes(app.LiveScreen);
            title(app.BFAxes, 'Brightfield')
            app.BFAxes.XTick = [];
            app.BFAxes.YTick = [];
            app.FinishButton = uibutton(app.LiveScreen, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.RaftEditFieldLabel = uilabel(app.LiveScreen);
            app.RaftEditFieldLabel.Text = 'Raft #';
            app.RaftDisplay = uieditfield(app.LiveScreen, 'numeric');
            app.RaftDisplay.Limits = [0 Inf];
            app.RaftDisplay.Editable = 'off';
            app.RaftDisplay.Value = 1;
            app.BlueAxes = uiaxes(app.LiveScreen);
            title(app.BlueAxes, 'Blue')
            app.BlueAxes.XTick = [];
            app.BlueAxes.YTick = [];
            app.RedAxes = uiaxes(app.LiveScreen);
            title(app.RedAxes, 'Red')
            app.RedAxes.XTick = [];
            app.RedAxes.YTick = [];
            app.GreenAxes = uiaxes(app.LiveScreen);
            title(app.GreenAxes, 'Green')
            app.GreenAxes.XTick = [];
            app.GreenAxes.YTick = [];
            app.SaveImages = uicheckbox(app.LiveScreen);
            app.SaveImages.Text = 'Save Image';
            app.SaveImages.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_LiveCheck

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LiveScreen)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LiveScreen)
        end
    end
end