classdef GUI_SelectMicrorafts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectMicroraftsUIFigure        matlab.ui.Figure
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        ArrayNumberLabel                matlab.ui.control.Label
        ArraysforIsolationListBoxLabel  matlab.ui.control.Label
        Arrays                          matlab.ui.control.ListBox
        DataAnalysisPanel               matlab.ui.container.Panel
        AnalysisMethodLabel             matlab.ui.control.Label
        CytotoxicityMethod              matlab.ui.control.DropDown
        NormalizationMethodDropDownLabel  matlab.ui.control.Label
        NormalizationMethod             matlab.ui.control.DropDown
        TracesCheckbox                  matlab.ui.control.CheckBox
        KillerDropDownLabel             matlab.ui.control.Label
        KillerType                      matlab.ui.control.DropDown
        FluorescenceInformationPanel    matlab.ui.container.Panel
        ColorofLivingCellsDropDownLabel  matlab.ui.control.Label
        LiveCell                        matlab.ui.control.DropDown
        ColorofDeadCellsDropDownLabel   matlab.ui.control.Label
        DeadCell                        matlab.ui.control.DropDown
        ColorofTCellsDropDownLabel      matlab.ui.control.Label
        TCells                          matlab.ui.control.DropDown
        SavingPrefencesPanel            matlab.ui.container.Panel
        ReleaseDataEditFieldLabel       matlab.ui.control.Label
        ReleaseDataNumber               matlab.ui.control.NumericEditField
        ShowSelectedMicroraftsButton    matlab.ui.control.Button
        TestButton                      matlab.ui.control.StateButton
        CombineData                     matlab.ui.control.CheckBox
        ViewLive                        matlab.ui.control.CheckBox
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, directory)
            %
            ID_Meth = find(strcmp(app.CytotoxicityMethod.Items,app.CytotoxicityMethod.Value));
            
            if ID_Meth == 5
                app.KillerType.Visible = 'on';
            else
                app.KillerType.Visible = 'off';
            end
    
        end

        % Button pushed function: ShowSelectedMicroraftsButton
        function ShowSelectedMicroraftsButtonPushed(app, event)
            global PDIR array_select Combine
            DIR = PDIR;
            %Create q matrix
            array_select = sort(str2num(cell2mat(app.Arrays.Value')))';
            
            %Create empty matrix to combine release data
            AllDelta = [];
            
            for q = array_select
                mkdir([DIR,'\',num2str(q),'\Rafts_to_Release'])
                %Identify channels for live cells, dead cells, and T cells
                Live_Filt = find(strcmp(app.LiveCell.Items,app.LiveCell.Value));
                Dead_Filt = find(strcmp(app.DeadCell.Items,app.DeadCell.Value));
                TC_Filt = find(strcmp(app.TCells.Items,app.TCells.Value));
                
                %Load data for T cells and live cells
                TC = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M3_C',num2str(TC_Filt),'.mat']);
                TC = ceil(TC.data);
                LiveArea = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M2_C',num2str(Live_Filt),'.mat']);
                LiveArea = LiveArea.data;
                LiveCount = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M3_C',num2str(Live_Filt),'.mat']);
                LiveCount = ceil(LiveCount.data);
                
                Skip = find(any(LiveArea==0,2));
                LiveArea = median(LiveArea,2);
                LiveArea(Skip) = median(LiveArea);
                
                ID_Meth = find(strcmp(app.CytotoxicityMethod.Items,app.CytotoxicityMethod.Value));
                NORMAL = find(strcmp(app.NormalizationMethod.Items,app.NormalizationMethod.Value));
                
                %If identifying best and worst killers: 1 is for best killers, 2 is for worst killers
                killType = find(strcmp(app.KillerType.Items,app.KillerType.Value));
                
                %Skip data points for which the area of live cells is in lower and upper 5%, and for which T cell count is not equal to 1 throughout experiment
                if any(ID_Meth == [1 2 3 4])
                    %For cytoxicity assays, skip data points as described above
                    cellMeasure = 1;
                    Skip = unique([Skip;find(LiveArea<quantile(LiveArea,0.05));find(LiveArea>quantile(LiveArea,0.95));find(any(TC~=1,2));]);
                elseif ID_Meth == 5
                    %Use this method when counting dead target cells in killing assays
                    cellMeasure = 2;
                    tCellInteg = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M7_C',num2str(Dead_Filt),'.mat']); %Load integ fluorescence of sytox green within T cell mask
                    tCellInteg = tCellInteg.data;
                    deadCount = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M3_C',num2str(Dead_Filt),'.mat']); %Load # of dead cells
                    deadCount = ceil(deadCount.data);
                    deltaDeath = deadCount(:,end) - deadCount(:,1); %Calculate change in dead cells over experiment
                    countDif = diff(deadCount,1,2)'; %Calculate the change in dead cells between timepoints (rate of killing: deaths/time)
                    
                    skipDif = unique([find(any(countDif > 3)), find(any(countDif < -3))])'; %Remove rafts with large differences in dead cells between timepoints
                    skipBounds = find(deltaDeath(:,1)<0 | deltaDeath(:,1)> 8);              %Remove cells with a change in dead cells outside of bounds
                    skipTCell = find((TC(:,1) ~= 1) | TC(:,end) ~= 1);                      %Remove cells without 1 T cell at the beginning and end
                    skipTarget = find(LiveCount(:,1) < 3 | (LiveCount(:,1)> 8));            %Remove rafts without 3-8 target cells at initial timepoint
                    skipDeadTCell =  find(tCellInteg(:,end) > 100000);                      %Remove rafts with dead T cell at final timepoint
                    skipInitialDead = find(deadCount(:,1) ~= 0);                            %Remove rafts containing dead cells at initial timepoint
                    
                    Skip = unique([skipTCell; skipDif; skipBounds; skipTarget; skipInitialDead; skipDeadTCell]);
                elseif ID_Meth == 6
                    %For proliferation studies, skip rafts for which the initial T cell count is not one; Also skip rafts for lower and upper 5% of area to avoid false cells
                    cellMeasure = 2;
                    Skip = [];
                    %Find rafts with large increases in cell counts or negative changes in cell count
                    countDif = diff(TC',1,1);
                    skipDif = unique([find(any(countDif > 10)), find(any(countDif <0))]);
                    %Skips: (1) Rafts with more than one T cell (2) More than one 'alive' cell (3) Rafts with 0 cells at any point; 2 dim searches rows with any() (4) Rafts without 2 or more T cells at end
                    Skip = unique([Skip;find(TC(:,1)~=1); find(LiveCount(:,1)~=1); find(any(TC==0,2)); find(TC(:,end)< 2); skipDif']);
                    Skip = unique([Skip;find(TC(:,1)~=1); find(LiveCount(:,1)~=1); find(TC(:,end)< 2); skipDif']);
                elseif ID_Meth == 7
                    %Use this method to identify rafts with single T cells at all timepoints
                    %%%%%%%%%%%%%%%%% IMPORTANT: CANNOT USE THIS METHOD WITH COMBINED DATA %%%%%%%%%%%%%%%%%%%
                    app.CombineData.Value = 0;
                    cellMeasure = 3;
                    deadCells = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M3_C',num2str(Dead_Filt),'.mat']);
                    deadCells = ceil(deadCells.data);
                    Skip = [find(median(TC,2)~=1); find(deadCells(:,end)~=0)];
                    size(Skip)
                end
                
                %Change cytotoxicity method based on selection
                switch ID_Meth
                    case 1
                        % Integrated fluorescence of all dead cells
                        Cytox = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M1_C',num2str(Dead_Filt),'.mat']);
                        Cytox = Cytox.data;
                    case 2
                        %Fluorescence of dead cells within mask
                        Cytox = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M7_C',num2str(Dead_Filt),'.mat']);
                        Cytox = Cytox.data;
                    case 3
                        %Fluorescence within live and dead --> sums the intensity values of dead cells for which the index of live and dead overlap
                        Live2 = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M4_C',num2str(Live_Filt),'.mat']);
                        Live2 = Live2.data;
                        Dead = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M4_C',num2str(Dead_Filt),'.mat']);
                        Dead = Dead.data;
                        Dead2 = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M5_C',num2str(Dead_Filt),'.mat']);
                        Dead2 = Dead2.data;
                        Cytox = zeros(size(TC));
                        for j = 1:size(Cytox,2)
                            for i = 1:size(Cytox,1)
                                if ~iscell(Dead{i,j})
                                    Dead{i,j} = {[]};
                                end
                                if ~iscell(Live2{i,j})
                                    Live2{i,j} = {[]};
                                end
                                if ~iscell(Dead2{i,j})
                                    Dead2{i,j} = {[]};
                                end
                                temp = zeros(size(Dead2{i,j},1),1);
                                for k = 1:size(Live2{i,j},1)
                                    %Look for pixels that are shared by both live and dead cells and then sum dead fluorescence in those pixels
                                    temp(k) = sum(Dead2{i,j}{k}(ismember(Dead{i,j}{k},Live2{i,j}{k})));
                                    if isnan(temp(k))
                                        temp(k) = 0;
                                    end
                                end
                                if isnan(mean(temp))
                                    Cytox(i,j) = 0;
                                else
                                    Cytox(i,j) = mean(temp);
                                end
                            end
                        end
                    case 4
                        %Total integrated fluorescence from dead cells
                        Cytox = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M6_C',num2str(Dead_Filt),'.mat']);
                        Cytox = Cytox.data;
                    case 5
                        %Total dead cells: cell count of target cells
                        Cytox = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M3_C',num2str(Dead_Filt),'.mat']);
                        Cytox = ceil(Cytox.data);
                    case 6
                        %Proliferaion of live cells: cell count of T cells
                        Prolif = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M3_C',num2str(TC_Filt),'.mat']);
                        Prolif = ceil(Prolif.data);
                    case 7
                        singleCell = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M3_C',num2str(TC_Filt),'.mat']);
                        singleCell = ceil(singleCell.data);
                        test2 = size(singleCell);
                    otherwise
                        return;
                end
                
                f = round(size(LiveArea,2)/2);
                if mod(f,2)==0
                    f = f+1;
                end
                %Change normalization method based on selection
                switch NORMAL
                    case 1
                        %Option: none
                        NORM = ones(size(TC));
                    case 2
                        %Option: median thresholded live intensity
                        LiveArea = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M1_C',num2str(Live_Filt),'.mat']);
                        LiveArea = LiveArea.data;
                        NORM = repmat(median(LiveArea,2),1,size(LiveArea,2));
                    case 3
                        %Option: median thresholded live area
                        LiveArea = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M2_C',num2str(Live_Filt),'.mat']);
                        LiveArea = LiveArea.data;
                        NORM = repmat(median(LiveArea,2),1,size(LiveArea,2));
                    case 4
                        %Option: median total live intensity
                        LiveArea = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M6_C',num2str(Live_Filt),'.mat']);
                        LiveArea = LiveArea.data;
                        NORM = repmat(median(LiveArea,2),1,size(LiveArea,2));
                    case 5
                        %Option: Smoothed thresholded live intensity -- uses Savitsky-Golay filtering to smooth data
                        LiveArea = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M1_C',num2str(Live_Filt),'.mat']);
                        LiveArea = LiveArea.data;
                        NORM = sgolayfilt(LiveArea,1,f,[],2);
                    case 6
                        %Option: Smoothed thresholded live area -- uses Savitsky-Golay filtering to smooth data
                        LiveArea = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M2_C',num2str(Live_Filt),'.mat']);
                        LiveArea = LiveArea.data;
                        NORM = sgolayfilt(LiveArea,1,f,[],2);
                    case 7
                        %Option: Smoothed thresholded total intensity -- uses Savitsky-Golay filtering to smooth data
                        LiveArea = load([DIR,'\data\ALL_DATA\A',num2str(q),'_M6_C',num2str(Live_Filt),'.mat']);
                        LiveArea = LiveArea.data;
                        NORM = sgolayfilt(LiveArea,1,f,[],2);
                    otherwise
                        return;
                end
                
                NORM(NORM<0) = 0;
                if cellMeasure == 1
                    Cytox = Cytox./NORM;
                    Cytox(isnan(Cytox)) = 0;
                    Cytox(isinf(Cytox)) = 0;
                    dataMeasure = Cytox;
                elseif cellMeasure == 2
                    if ID_Meth == 5
                        dataMeasure = Cytox;
                    elseif ID_Meth == 6
                        dataMeasure = Prolif;
                    end
                end
                
                %Remove any traces that have a large drop if selected
                if app.TracesCheckbox.Value >0
                    drop = diff(dataMeasure,1,2);
                    change = drop;
                    drop(drop>=0) = [];
                    Skip = unique([Skip;find(any(change<(mean(drop)-(2*std(drop))),2))]);
                end
                
                
                if any(cellMeasure == [1 2]) %Execute for cytotoxicity and proliferation analysis (not for single cell IDing)
                    if app.CombineData.Value == 0
                        Delta = dataMeasure(:,end)-dataMeasure(:,1);
                        %display(Delta)
                        %%%% Note that setting Delta to -Inf with proliferation does not work!!!!!!!!!!!
                        Delta(Skip) = -Inf;
%                         Delta(Delta<=0) = -Inf;
                        [sortedMeasure,ind] = sort(Delta,'descend');
                        %Release locations outputs a cell array with raft locations only
                        Release_Locations{q} = ind(1:length(Delta)-length(find(Delta==-Inf)));
                        %display(Release_Locations)
                    else
                        Delta = dataMeasure(:,end)-dataMeasure(:,1);
                        Delta(Skip) = -Inf;
%                         Delta(Delta<=0) = -Inf;
                        for i=1:length(Delta)
                            Delta(i,2) = i;
                            Delta(i,3) = q;
                        end
                        AllDelta = [AllDelta; Delta];
                    end
                else
                    %Create a list of single cells to release; randomly order list to avoid picking adjacent rafts
                    singleCell_temp = singleCell;
                    test = 1
                    %Create an index for rafts with a single cell, then remove skips
                    scIndex = [1:length(singleCell_temp)];
                    test3 = size(scIndex)
                    scIndex(Skip) = [];
                    %remove skipped rafts
                    %%singleCell_temp(Skip,:) = [];
                    %Create a random order for the single cell rafts and keep index
                    rng('shuffle')
                    shuffleIndex = randi([1 size(scIndex,2)],1, size(scIndex,2));
                    Release_Locations{q} = scIndex(shuffleIndex)';
                end
            end
            
            if app.CombineData.Value == 1
                %Sort the combined data based on the first column (Delta value)
                
                if ID_Meth == 5 && killType == 2
                    DeltaSorted = sortrows(AllDelta,'ascend');
                else
                    DeltaSorted = sortrows(AllDelta,'descend');
                end
                %Discard any rafts with an ininite
                Release_Locations = DeltaSorted((1:length(DeltaSorted)-length(find(DeltaSorted==-Inf))),:);
                %Release locations outputs a three column array:  (1) Measured parameter (2) Raft index (3) Array
            end
            
            %Save release locations
            save([DIR,'\Release_Data.mat'],'Release_Locations','array_select');
            
            %Pass information to new GUI
            
            %If data was combined, set to 1; passes info to ViewMicrorafts GUI
            Combine = app.CombineData.Value;
            %display(Release_Locations)
            if ~isempty(Release_Locations)
                if app.ViewLive.Value == 0
                    %Open GUI for viewing selected microrafts
                    GUI_ViewMicrorafts
                else
                    app.delete
                end
                %Close current GUI
                app.delete
            else
                f = msgbox('Zero microrafts selected for isolation; please modify data analysis method');
            end
            %Display the number of rafts that you can choose from; check for cell (not combined) or matrix (combined)
            try
                size(Release_Locations{1})
            catch
                size(Release_Locations)
            end
            
        end

        % Value changed function: TestButton
        function TestButtonValueChanged(app, event)
            
        end

        % Value changed function: CytotoxicityMethod
        function CytotoxicityMethodValueChanged(app, event)
            ID_Meth = find(strcmp(app.CytotoxicityMethod.Items,app.CytotoxicityMethod.Value));
            if ID_Meth == 5
                app.KillerType.Visible = 'on';
            else
                app.KillerType.Visible = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectMicroraftsUIFigure = uifigure;
            app.SelectMicroraftsUIFigure.Name = 'Select Microrafts';
            app.Label = uilabel(app.SelectMicroraftsUIFigure);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.SelectMicroraftsUIFigure);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.SelectMicroraftsUIFigure);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.SelectMicroraftsUIFigure);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.SelectMicroraftsUIFigure);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.SelectMicroraftsUIFigure);
            app.Label_6.Text = '6';
            app.ArrayNumberLabel = uilabel(app.SelectMicroraftsUIFigure);
            app.ArrayNumberLabel.Text = 'Array Number';
            app.ArraysforIsolationListBoxLabel = uilabel(app.SelectMicroraftsUIFigure);
            app.ArraysforIsolationListBoxLabel.Text = 'Arrays for Isolation';
            app.Arrays = uilistbox(app.SelectMicroraftsUIFigure);
            app.Arrays.Items = {'1', '2', '3', '4', '5', '6'};
            app.Arrays.Multiselect = 'on';
            app.Arrays.Value = {'1'};
            app.DataAnalysisPanel = uipanel(app.SelectMicroraftsUIFigure);
            app.DataAnalysisPanel.Title = 'Data Analysis';
            app.AnalysisMethodLabel = uilabel(app.DataAnalysisPanel);
            app.AnalysisMethodLabel.Text = 'Analysis Method';
            app.CytotoxicityMethod = uidropdown(app.DataAnalysisPanel);
            app.CytotoxicityMethod.Items = {'Fluorescence within Dead', 'Fluorescence within Live', 'Fluorescence within Live & Dead', 'Total Fluorescence', 'Total Dead', 'Proliferation', 'Single Cell'};
            app.CytotoxicityMethod.ValueChangedFcn = createCallbackFcn(app, @CytotoxicityMethodValueChanged, true);
            app.CytotoxicityMethod.Value = 'Total Dead';
            app.NormalizationMethodDropDownLabel = uilabel(app.DataAnalysisPanel);
            app.NormalizationMethodDropDownLabel.Text = 'Normalization Method';
            app.NormalizationMethod = uidropdown(app.DataAnalysisPanel);
            app.NormalizationMethod.Items = {'None', 'Median thresholded live intensity', 'Median thresholded Live area', 'Median total Live intensity', 'Smoothed thresholded Live intensity', 'Smoothed Thresholded Live Area', 'Smoothed Total Live Intensity'};
            app.NormalizationMethod.Value = 'None';
            app.TracesCheckbox = uicheckbox(app.DataAnalysisPanel);
            app.TracesCheckbox.Text = 'Remove Traces with Large Drops';
            app.KillerDropDownLabel = uilabel(app.DataAnalysisPanel);
            app.KillerDropDownLabel.Text = 'Killer';
            app.KillerType = uidropdown(app.DataAnalysisPanel);
            app.KillerType.Items = {'Best', 'Worst'};
            app.KillerType.Value = 'Best';
            app.FluorescenceInformationPanel = uipanel(app.SelectMicroraftsUIFigure);
            app.FluorescenceInformationPanel.Title = 'Fluorescence Information';
            app.ColorofLivingCellsDropDownLabel = uilabel(app.FluorescenceInformationPanel);
            app.ColorofLivingCellsDropDownLabel.Text = 'Color of Living Cells';
            app.LiveCell = uidropdown(app.FluorescenceInformationPanel);
            app.LiveCell.Items = {'UV/Blue', 'V/Blue', 'Green', 'Red', 'Far Red'};
            app.LiveCell.Value = 'UV/Blue';
            app.ColorofDeadCellsDropDownLabel = uilabel(app.FluorescenceInformationPanel);
            app.ColorofDeadCellsDropDownLabel.Text = 'Color of Dead Cells';
            app.DeadCell = uidropdown(app.FluorescenceInformationPanel);
            app.DeadCell.Items = {'UV/Blue', 'V/Blue', 'Green', 'Red', 'Far Red'};
            app.DeadCell.Value = 'Green';
            app.ColorofTCellsDropDownLabel = uilabel(app.FluorescenceInformationPanel);
            app.ColorofTCellsDropDownLabel.Text = 'Color of T Cells';
            app.TCells = uidropdown(app.FluorescenceInformationPanel);
            app.TCells.Items = {'UV/Blue', 'V/Blue', 'Green', 'Red', 'Far Red'};
            app.TCells.Value = 'Red';
            app.SavingPrefencesPanel = uipanel(app.SelectMicroraftsUIFigure);
            app.SavingPrefencesPanel.Title = 'Saving Prefences';
            app.ReleaseDataEditFieldLabel = uilabel(app.SavingPrefencesPanel);
            app.ReleaseDataEditFieldLabel.Text = 'Release Data #';
            app.ReleaseDataNumber = uieditfield(app.SavingPrefencesPanel, 'numeric');
            app.ReleaseDataNumber.Limits = [1 10];
            app.ReleaseDataNumber.Value = 1;
            app.ShowSelectedMicroraftsButton = uibutton(app.SavingPrefencesPanel, 'push');
            app.ShowSelectedMicroraftsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSelectedMicroraftsButtonPushed, true);
            app.ShowSelectedMicroraftsButton.Text = 'Show Selected Microrafts';
            app.TestButton = uibutton(app.SavingPrefencesPanel, 'state');
            app.TestButton.ValueChangedFcn = createCallbackFcn(app, @TestButtonValueChanged, true);
            app.TestButton.Text = 'Test Button';
            app.CombineData = uicheckbox(app.SavingPrefencesPanel);
            app.CombineData.Text = 'Combine Microrafts Release Data';
            app.CombineData.Value = true;
            app.ViewLive = uicheckbox(app.SavingPrefencesPanel);
            app.ViewLive.Text = 'View Live';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_SelectMicrorafts(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectMicroraftsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectMicroraftsUIFigure)
        end
    end
end