classdef GUI_ViewMicrorafts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ViewMicroraftsUIFigure  matlab.ui.Figure
        BFAxes                  matlab.ui.control.UIAxes
        CombinedChannelsAxes    matlab.ui.control.UIAxes
        KeepButton              matlab.ui.control.Button
        DiscardButton           matlab.ui.control.Button
        FinishButton            matlab.ui.control.Button
        TimeIncrementLabel      matlab.ui.control.Label
        TimeIncrement           matlab.ui.control.NumericEditField
        TestCodeButton          matlab.ui.control.Button
        RaftDisplayLabel        matlab.ui.control.Label
        RaftDisplay             matlab.ui.control.NumericEditField
        NextArrayButton         matlab.ui.control.Button
        ArrayLabel              matlab.ui.control.Label
        ArrayNumber             matlab.ui.control.NumericEditField
        TotalSavedLabel         matlab.ui.control.Label
        TotalSaved              matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function displayRaft(app, DIR, array, timepoint, raft, L_array, Loc_cell2, PFilters)
            
            %Pull out data for raft location (RL) --> array, row, column, local address,
            % global address
            RL = Loc_cell2{array,timepoint}(raft,:);
            row = RL(:,2);
            column = RL(:,3);
            LA = RL(:,4);
            %Find labeled array using rows and columns
            L = L_array{row, column};
            %Create bounding box for microraft
            ZL = regionprops(L,'Centroid','BoundingBox');
            BB = [ZL(LA).BoundingBox];
            TIME = app.TimeIncrement.Value;
            CropImage = cell(6,1);
            Filters = [PFilters 1];
            %%%%%%%%%%%% OLD CODE: displayed images in separate axes %%%%%%%%%%%%%%
            %             parfor z = 1:6
            %                 if Filters(z)==1
            %                     CropImage2 = cell(1,TIME+1);
            %                     for TT = 0:TIME
            %                         STRING = {'Blue','Violet','Green','Red','FarRed','BF'};
            %                         img=imread([DIR,'\',num2str(array),'\', num2str(row),'x', num2str(column),'\', num2str(TT,'%02.0f'),'_',STRING{z},'.tif']);
            %                         if z ~= 6
            %                             CropImage2{TT+1} = imadjust(imcrop(img,BB), [0.01, 0.12],[]);
            %                         else
            %                             CropImage2{TT+1} = imcrop(img,BB);
            %                         end
            %                     end
            %                     CropImage{z} = cell2mat(CropImage2);
            %                 end
            %             end
            
            
            %             for z = [find(PFilters),6]
            %                 switch z
            %                     case 1
            %                         imshow(CropImage{z},[],'Parent',app.CombinedChannelsAxes);
            %                     case 2
            %                         imshow(CropImage{z},[],'Parent',app.CombinedChannelsAxes);
            %                     case 3
            %                         imshow(CropImage{z},[],'Parent',app.GreenAxes);
            %                     case 4
            %                         imshow(CropImage{z},[],'Parent',app.RedAxes);
            %                     case 5
            %                         imshow(CropImage{z},[],'Parent',app.RedAxes);
            %                     case 6
            %                         imshow(CropImage{z},[],'Parent',app.BFAxes);
            %                 end
            %             end
            %%%%%%%% New code: displays fluorescence images in one axix %%%%%%%%%%%%%
            parfor z = 1:6
                if Filters(z)==1
                    CropImage2 = cell(1,TIME+1);
                    for TT = 0:TIME
                        STRING = {'Blue','Violet','Green','Red','FarRed','BF'};
                        img=imread([DIR,'\',num2str(array),'\', num2str(row),'x', num2str(column),'\', num2str(TT,'%02.0f'),'_',STRING{z},'.tif']);
                        if z == 1 || z== 2 %blue channels
                            CropImage2{TT+1} = imadjust(imcrop(img,BB), [0.01, 0.15],[]);
                        elseif z == 3 %green channel
                            CropImage2{TT+1} = imadjust(imcrop(img,BB), [0.01, 0.3],[]);
                        elseif z == 4 || z == 5 %red channels
                            CropImage2{TT+1} = imadjust(imcrop(img,BB), [0.01, 0.1],[]);
                        else %brightfield
                            CropImage2{TT+1} = imcrop(img,BB);
                        end
                    end
                    CropImage{z} = cell2mat(CropImage2);
                end
            end
            
            %Determine which blue channel is present or set to blank image if none
            if Filters(1)
                blueImages = CropImage{1};
            elseif Filters(2)
                blueImages = CropImage{2};
            else
                blueImages = zeros(size(CropImage{6}));
            end
            %Determine which red channel is present or set to blank image if none
            if Filters(4)
                redImages = CropImage{4};
            elseif Filters(5)
                redImages = CropImage{5};
            else
                redImages = zeros(size(CropImage{6}));
            end
            %Determine if green channel is present; set to blank image if not
            if Filters(3)
                greenImages = CropImage{3};
            else
                greenImages = zeros(size(CropImage{6}));
            end
            %Combine images into a single RGB image
            combinedImage = cat(3, redImages,greenImages,blueImages);
            
            imshow(combinedImage,[],'InitialMagnification',150,'Parent',app.CombinedChannelsAxes);
            imshow(CropImage{6},[],'InitialMagnification',150,'Parent',app.BFAxes);
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, directory)
            %DIR = directory;
            %Load directory and release data to get address of rafts to release
            global PDIR PFilters array_select Combine Rel_Loc
            DIR = PDIR;
            
            Rel_Loc = [];
            %%%%%%%% use this code if data was combined
            if Combine == 1
                load([DIR,'\Release_Data.mat']); %loads Release_Locations
                load([DIR,'\data\Loc_cell2.mat']);
                load([DIR,'\data\L_cell\A',num2str(Release_Locations(1,3)),'_T1.mat']);
                raft = Release_Locations(1,2);
                timepoint = 1;
                array = Release_Locations(1,3);
                app.ArrayNumber.Value = array;
            else
                load([DIR,'\Release_Data.mat']); %loads Release_Locations
                load([DIR,'\data\Loc_cell2.mat']);
                load([DIR,'\data\L_cell\A',num2str(array_select(1)),'_T1.mat']);
                raft = Release_Locations{1,array_select(1)}(1);
                timepoint = 1;
                array = array_select(1);
                app.ArrayNumber.Value = array;
            end
            
            parpool;
            %Set limits to Time incement changes to avoid errors
            app.TimeIncrement.Limits = [0 size(Loc_cell2,2)-1];
            %Determine the number of timepoints saved in experiment and then set time increment
            app.TimeIncrement.Value = size(Loc_cell2,2)-1;
            
            displayRaft(app, DIR, array, timepoint, raft, L_array, Loc_cell2, PFilters)
            
            %Set raft display value to 1, to indicate the first image is being viewed
            app.RaftDisplay.Value = 1;
            
        end

        % Button pushed function: TestCodeButton
        function TestCodeButtonPushed(app, event)
            
        end

        % Button pushed function: KeepButton
        function KeepButtonPushed(app, event)
            global PDIR PFilters array_select Combine Rel_Loc
            DIR = PDIR;
            load([DIR,'\Release_Data.mat']);
            load([DIR,'\data\Loc_cell2.mat']);
            
            Combine = ~iscell(Release_Locations);
            
            %Save raft location for release
            RaftCount = app.RaftDisplay.Value;
            
            if Combine == 1
                load([DIR,'\data\L_cell\A',num2str(Release_Locations(RaftCount,3)),'_T1.mat']);
                Rel_Loc = [Rel_Loc; Release_Locations(RaftCount,2),Release_Locations(RaftCount,3)];
                numLimit = size(Release_Locations,1);
            else
                load([DIR,'\data\L_cell\A',num2str(app.ArrayNumber.Value),'_T1.mat']);
                Rel_Loc = [Rel_Loc; Release_Locations{1,app.ArrayNumber.Value}(RaftCount),app.ArrayNumber.Value];
                numLimit = size(Release_Locations{1,app.ArrayNumber.Value},1);
            end
            app.TotalSaved.Value = app.TotalSaved.Value + 1;
            %%%%%%%%% Code for combined data --> load next set of images
            
            %Only update images, raft number, etc. if there are more rafts for the array selected
            if RaftCount < numLimit
                RaftCount = app.RaftDisplay.Value + 1;
                if Combine == 1
                    load([DIR,'\data\L_cell\A',num2str(Release_Locations(RaftCount,3)),'_T1.mat']);
                    raft = Release_Locations(RaftCount,2);
                    timepoint = 1;
                    array = Release_Locations(RaftCount,3);
                    app.ArrayNumber.Value = array;
                else
                    load([DIR,'\data\L_cell\A',num2str(app.ArrayNumber.Value),'_T1.mat']);
                    raft = Release_Locations{1, app.ArrayNumber.Value}(RaftCount);
                    timepoint = 1;
                    array = app.ArrayNumber.Value;
                end
                %Set limits to Time incement changes to avoid errors
                app.TimeIncrement.Limits = [0 size(Loc_cell2,2)-1];
                RaftCount = app.RaftDisplay.Value + 1;
                displayRaft(app, DIR, array, timepoint, raft, L_array, Loc_cell2, PFilters)
                %Update raft display value
                app.RaftDisplay.Value = RaftCount;
                %Add a count to advance to the next image
                %                 RaftCount = RaftCount+1;
            else
                f = msgbox('No more rafts identified for release in this array');
            end
            
        end

        % Value changed function: TimeIncrement
        function TimeIncrementValueChanged(app, event)
            %When the time increment value is changed, update the image axes to have correct number of time points
            global PDIR PFilters array_select Combine Rel_Loc
            DIR = PDIR;
            
            load([DIR,'\Release_Data.mat']);
            load([DIR,'\data\Loc_cell2.mat']);
            
            %%%%%%%%% Code for combined data --> reload images
            RaftCount = app.RaftDisplay.Value
            if Combine == 1
                load([DIR,'\data\L_cell\A',num2str(Release_Locations(RaftCount,3)),'_T1.mat']);
                raft = Release_Locations(RaftCount,2);
                timepoint = 1;
                array = Release_Locations(RaftCount,3);
                app.ArrayNumber.Value = array;
            else
                load([DIR,'\data\L_cell\A',num2str(app.ArrayNumber.Value),'_T1.mat']);
                raft = Release_Locations{1,app.ArrayNumber.Value}(RaftCount);
                timepoint = 1;
                array = app.ArrayNumber.Value;
            end
            
            %Set limits to Time incement changes to avoid errors
            app.TimeIncrement.Limits = [0 size(Loc_cell2,2)-1];
            displayRaft(app, DIR, array, timepoint, raft, L_array, Loc_cell2, PFilters)
            
        end

        % Button pushed function: DiscardButton
        function DiscardButtonPushed(app, event)
            %When discard button is pressed; move to next raft and refresh images
            %Do not save release location (or delete?)
            global PDIR PFilters array_select Combine Rel_Loc
            DIR = PDIR;
            
            load([DIR,'\Release_Data.mat']);
            load([DIR,'\data\Loc_cell2.mat']);
            
            if Combine == 1
                numLimit = size(Release_Locations,1);
            else
                numLimit = size(Release_Locations{1,app.ArrayNumber.Value},1);
            end
            
            if app.RaftDisplay.Value < numLimit
                RaftCount = app.RaftDisplay.Value + 1;
                
                if Combine == 1
                    load([DIR,'\data\L_cell\A',num2str(Release_Locations(RaftCount,3)),'_T1.mat']);
                    raft = Release_Locations(RaftCount,2);
                    timepoint = 1;
                    array = Release_Locations(RaftCount,3);
                    app.ArrayNumber.Value = array;
                else
                    load([DIR,'\data\L_cell\A',num2str(app.ArrayNumber.Value),'_T1.mat']);
                    raft = Release_Locations{1,app.ArrayNumber.Value}(RaftCount);
                    timepoint = 1;
                    array = app.ArrayNumber.Value;
                end
                
                %Set limits to Time incement changes to avoid errors
                app.TimeIncrement.Limits = [0 size(Loc_cell2,2)-1];
                displayRaft(app, DIR, array, timepoint, raft, L_array, Loc_cell2, PFilters)
                
                %Update raft display value
                app.RaftDisplay.Value = RaftCount;
                
                %Add a count to advance to the next image
                %             RaftCount = RaftCount+1;
            else
                f = msgbox('No more rafts identified for release in this array');
            end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            global PDIR PFilters array_select Combine Rel_Loc
            DIR = PDIR;
            %Remove any duplicate release locations
            Rel_Loc = unique(Rel_Loc,'rows','stable');
            %Save release data file --> Rel_Loc.mat --> Used by GUI_IsolateMicrorafts
            %Save list of arrays that rafts will be isolated from --> used by GUI_IsolateMicrorafts
            save([DIR,'\Rel_Loc.mat'],'Rel_Loc','array_select');
            %Close app and return to main app, close parallel pool
            app.delete
            delete(gcp)
        end

        % Close request function: ViewMicroraftsUIFigure
        function ViewMicroraftsUIFigureCloseRequest(app, event)
            %Close app and return to main app, close parallel pool
            delete(app)
            delete(gcp)
        end

        % Button pushed function: NextArrayButton
        function NextArrayButtonPushed(app, event)
            global PDIR PFilters array_select Combine Rel_Loc
            DIR = PDIR;
            %Move to the next array (from selected arrays), if not on the last array
            if (Combine == 0) && (app.ArrayNumber.Value ~=array_select(end))
                %app.ArrayNumber.Value = array_select(app.ArrayNumber.Value+1);
                app.ArrayNumber.Value = array_select(find(app.ArrayNumber.Value==array_select)+1);
                RaftCount = 1;
                load([DIR,'\Release_Data.mat']);
                load([DIR,'\data\Loc_cell2.mat']);
                load([DIR,'\data\L_cell\A',num2str(app.ArrayNumber.Value),'_T1.mat']);
                raft = Release_Locations{1,app.ArrayNumber.Value}(RaftCount);
                timepoint = 1;
                array = app.ArrayNumber.Value;
                app.RaftDisplay.Value = 1;
                %Set limits to Time incement changes to avoid errors
                app.TimeIncrement.Limits = [0 size(Loc_cell2,2)-1];
                displayRaft(app, DIR, array, timepoint, raft, L_array, Loc_cell2, PFilters)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ViewMicroraftsUIFigure = uifigure;
            app.ViewMicroraftsUIFigure.Name = 'ViewMicrorafts';
            app.ViewMicroraftsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ViewMicroraftsUIFigureCloseRequest, true);
            app.BFAxes = uiaxes(app.ViewMicroraftsUIFigure);
            title(app.BFAxes, 'Brightfield')
            app.BFAxes.XTick = [];
            app.BFAxes.YTick = [];
            app.BFAxes.YTickLabel = '';
            app.CombinedChannelsAxes = uiaxes(app.ViewMicroraftsUIFigure);
            title(app.CombinedChannelsAxes, 'Combined Fluorescence Channels')
            app.CombinedChannelsAxes.XTick = [];
            app.CombinedChannelsAxes.YTick = [];
            app.KeepButton = uibutton(app.ViewMicroraftsUIFigure, 'push');
            app.KeepButton.ButtonPushedFcn = createCallbackFcn(app, @KeepButtonPushed, true);
            app.KeepButton.Text = 'Keep';
            app.DiscardButton = uibutton(app.ViewMicroraftsUIFigure, 'push');
            app.DiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardButtonPushed, true);
            app.DiscardButton.Text = 'Discard';
            app.FinishButton = uibutton(app.ViewMicroraftsUIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.TimeIncrementLabel = uilabel(app.ViewMicroraftsUIFigure);
            app.TimeIncrementLabel.Text = 'Time Increment';
            app.TimeIncrement = uieditfield(app.ViewMicroraftsUIFigure, 'numeric');
            app.TimeIncrement.ValueChangedFcn = createCallbackFcn(app, @TimeIncrementValueChanged, true);
            app.TimeIncrement.Value = 6;
            app.TestCodeButton = uibutton(app.ViewMicroraftsUIFigure, 'push');
            app.TestCodeButton.ButtonPushedFcn = createCallbackFcn(app, @TestCodeButtonPushed, true);
            app.TestCodeButton.Text = 'Test Code';
            app.RaftDisplayLabel = uilabel(app.ViewMicroraftsUIFigure);
            app.RaftDisplayLabel.Text = 'Raft Display #';
            app.RaftDisplay = uieditfield(app.ViewMicroraftsUIFigure, 'numeric');
            app.RaftDisplay.Editable = 'off';
            app.RaftDisplay.Value = 1;
            app.NextArrayButton = uibutton(app.ViewMicroraftsUIFigure, 'push');
            app.NextArrayButton.ButtonPushedFcn = createCallbackFcn(app, @NextArrayButtonPushed, true);
            app.NextArrayButton.Text = 'Next Array';
            app.ArrayLabel = uilabel(app.ViewMicroraftsUIFigure);
            app.ArrayLabel.Text = 'Array #';
            app.ArrayNumber = uieditfield(app.ViewMicroraftsUIFigure, 'numeric');
            app.ArrayNumber.Editable = 'off';
            app.ArrayNumber.Value = 1;
            app.TotalSavedLabel = uilabel(app.ViewMicroraftsUIFigure);
            app.TotalSavedLabel.Text = 'Total Saved';
            app.TotalSaved = uieditfield(app.ViewMicroraftsUIFigure, 'numeric');
            app.TotalSaved.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_ViewMicrorafts(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ViewMicroraftsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ViewMicroraftsUIFigure)
        end
    end
end