classdef InvertedMVXGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ScanModeTab                   matlab.ui.container.Tab
        ScanModeLabel                 matlab.ui.control.Label
        Histogram                     matlab.ui.control.UIAxes
        ExposuremsLabel               matlab.ui.control.Label
        Exp                           matlab.ui.control.NumericEditField
        MagnificationDropDownLabel    matlab.ui.control.Label
        Magnification                 matlab.ui.control.DropDown
        ObjectiveDropDownLabel        matlab.ui.control.Label
        Objective                     matlab.ui.control.DropDown
        FilterDropDownLabel           matlab.ui.control.Label
        Filter                        matlab.ui.control.DropDown
        SaveLocationEditFieldLabel    matlab.ui.control.Label
        SaveLocation                  matlab.ui.control.EditField
        ScanArray                     matlab.ui.control.Button
        LiveButton                    matlab.ui.control.StateButton
        TestCodeButton                matlab.ui.control.Button
        BrowseButton                  matlab.ui.control.Button
        ArrayInfoPanel                matlab.ui.container.Panel
        Four_Well                     matlab.ui.control.CheckBox
        RaftSizeumEditFieldLabel      matlab.ui.control.Label
        RaftWidth                     matlab.ui.control.NumericEditField
        RaftGapumEditFieldLabel       matlab.ui.control.Label
        RaftGap                       matlab.ui.control.NumericEditField
        WellDepthumEditFieldLabel     matlab.ui.control.Label
        Well_Depth                    matlab.ui.control.NumericEditField
        ArrayWidthLabel               matlab.ui.control.Label
        ArraySize                     matlab.ui.control.NumericEditField
        QuantityEditFieldLabel        matlab.ui.control.Label
        Array_Quant                   matlab.ui.control.NumericEditField
        ExposurePanel                 matlab.ui.container.Panel
        CHANNEL1                      matlab.ui.control.CheckBox
        CHANNEL2                      matlab.ui.control.CheckBox
        CHANNEL3                      matlab.ui.control.CheckBox
        CHANNEL4                      matlab.ui.control.CheckBox
        CHANNEL5                      matlab.ui.control.CheckBox
        UVBLabel                      matlab.ui.control.Label
        EXP1                          matlab.ui.control.NumericEditField
        VBlueLabel                    matlab.ui.control.Label
        EXP2                          matlab.ui.control.NumericEditField
        GreenEditFieldLabel           matlab.ui.control.Label
        EXP3                          matlab.ui.control.NumericEditField
        RedEditFieldLabel             matlab.ui.control.Label
        EXP4                          matlab.ui.control.NumericEditField
        FarRedEditFieldLabel          matlab.ui.control.Label
        EXP5                          matlab.ui.control.NumericEditField
        BrightfieldEditFieldLabel     matlab.ui.control.Label
        BFEXP                         matlab.ui.control.NumericEditField
        SizeFiltersPanel              matlab.ui.container.Panel
        CellandClumpRadiusumLabel     matlab.ui.control.Label
        LowerEditFieldLabel           matlab.ui.control.Label
        Cell_Lower                    matlab.ui.control.NumericEditField
        UpperEditFieldLabel           matlab.ui.control.Label
        Cell_Upper                    matlab.ui.control.NumericEditField
        ClumpEditFieldLabel           matlab.ui.control.Label
        Clump_Max                     matlab.ui.control.NumericEditField
        TimelapsePanel                matlab.ui.container.Panel
        TotalDurationhEditFieldLabel  matlab.ui.control.Label
        TotalDuration                 matlab.ui.control.NumericEditField
        IntervalminEditFieldLabel     matlab.ui.control.Label
        Interval                      matlab.ui.control.NumericEditField
        NumberofTimepointsEditFieldLabel  matlab.ui.control.Label
        TimePoints                    matlab.ui.control.NumericEditField
        BrifghtfieldIntensitySpinnerLabel  matlab.ui.control.Label
        BFAmps                        matlab.ui.control.Spinner
        LiveOverrideCheckBox          matlab.ui.control.CheckBox
        StatusLampLabel               matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        CollectionModeTab             matlab.ui.container.Tab
        CollectionModeLabel           matlab.ui.control.Label
        AutomatedSelectionButton      matlab.ui.control.Button
        ReScanArrayButton             matlab.ui.control.Button
        CalibrateNeedleButton         matlab.ui.control.Button
        CalibrateWandButton           matlab.ui.control.Button
        IsolateMicroraftsButton       matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        SelectMicroraftsLamp          matlab.ui.control.Lamp
        RescanLamp                    matlab.ui.control.Lamp
        Lamp_4Label                   matlab.ui.control.Label
        CalibrateNeedleLamp           matlab.ui.control.Lamp
        Lamp_5Label                   matlab.ui.control.Label
        CalibrateWandLamp             matlab.ui.control.Lamp
        Lamp_6Label                   matlab.ui.control.Label
        IsolateMicroraftsLamp         matlab.ui.control.Lamp
        ArrayNumberLabel              matlab.ui.control.Label
        ArrayNumberRescan             matlab.ui.control.NumericEditField
        LiveScreenButton              matlab.ui.control.Button
        LoadArraySelection            matlab.ui.control.CheckBox
        HomeStageButton               matlab.ui.control.Button
        CheckforCellsButton           matlab.ui.control.Button
        StatusInfoTextAreaLabel       matlab.ui.control.Label
        Status                        matlab.ui.control.TextArea
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global  mmc a LED
            
            z = warndlg('Please make sure that the stage, camera, and ardiuno are all on and connected to the computer!');
            uiwait(z)
            
            
            %Import the MicroManager core and load system configuration file
            %Set filter for brightfield
            import mmcorej.*;
            mmc=CMMCore;
            mmc.loadSystemConfiguration('C:\Program Files\Micro-Manager-1.4\InvertedMVX2.cfg');
            mmc.setTimeoutMs(20000);
            mmc.enableDebugLog(1);
            
            %Set filter to brightfield and set filter change speed to 1
            mmc.setProperty('Wheel_EM','Speed','1');
            mmc.setProperty('Wheel_EX','Speed','1');
            %Set Zstage speed
            mmc.setProperty('ZStage','MaxSpeed','50')
            
            %mmc.setProperty('Camera','MINIMUM ACQUISITION TIMEOUT','5000')
            mmc.waitForSystem()
            SetFilter(mmc,3);
            mmc.waitForSystem()
            SetFilter(mmc,0);
            mmc.waitForSystem()
            
            %connect to LED backlight and set mAmps to 0
            LED= tcpip('192.168.0.1',777);
            fopen(LED);
            LED.Terminator = 'LF';
            powervalue=num2str(0);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue,'SET:MODE:CHANNEL1,1;','SET:MODE:CHANNEL2,1;', ';',])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
        end

        % Button pushed function: TestCodeButton
        function TestCodeButtonPushed(app, event)
            
        end

        % Value changed function: LiveButton
        function LiveButtonValueChanged(app, event)
            global himage TIMER mmc vid LED
            value = app.LiveButton.Value;
            %Create scenarios for when live button is on; video turning on/off brightfield light
            if value == 1
                mmc.setExposure(app.Exp.Value)
                app.LiveButton.Text='Stop';
                %find any active timers and stop them
                if isa(TIMER,'timer')
                    if isvalid(TIMER)
                        stop(TIMER)
                    end
                end
                delete(timerfindall)
                %Create external figure for viewing video feed -- set figure position, turn off axis tics
                vid=figure('Position', [1016 256 695 573]);
                View=axes(vid);
                axis off
                [TIMER,himage] = StartLiveHist(View,app.Histogram,app);
                
                %If filter is set to brightfield and the overrride box is checked then set brightfield light to value
                if (strcmpi(app.Filter.Value,'Brightfield')) || (app.LiveOverrideCheckBox.Value==1)
                    value = app.BFAmps.Value;
                    powervalue=num2str(value);
                    fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                    pause(0.5)
                    while LED.BytesAvailable>0
                        LEDMessage = fscanf(LED);
                    end
                end
                
            else
                app.LiveButton.Text='Live';
                mmc.stopSequenceAcquisition();
                stop(TIMER)
                %             delete(timerfindall)
                delete(TIMER)
                close(vid)
                clear -global TIMER
                cla(app.Histogram)
                %if the ovverride checkbox is not checked then turn off brightfield light
                if app.LiveOverrideCheckBox.Value==0
                    powervalue=num2str(0);
                    fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                    pause(0.5)
                    while LED.BytesAvailable>0
                        LEDMessage = fscanf(LED);
                    end
                end
                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global mmc LED vid TIMER
            mmc.stopSequenceAcquisition();
            if isa(TIMER,'timer')
                if isvalid(TIMER)
                    stop(TIMER)
                end
            end
            delete(timerfindall)
            if ishandle(vid)
                close(vid)
            end
            clear -global TIMER
            cla(app.Histogram)
            
            delete(app);
            mmc.unloadAllDevices
            
            %turn off brightfield light
            powervalue=num2str(0);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            fclose(LED);
        end

        % Value changed function: BFAmps
        function BFAmpsValueChanged(app, event)
            global LED
            %turn on light if live button is checked and brightfield is on, or if the override check box is checked
            if (app.LiveButton.Value==1 && strcmpi(app.Filter.Value,'Brightfield')) || (app.LiveOverrideCheckBox.Value==1)
                value = app.BFAmps.Value;
                powervalue=num2str(value);
                fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                pause(0.5)
                while LED.BytesAvailable>0
                    LEDMessage = fscanf(LED);
                end
            end
        end

        % Value changed function: Filter
        function FilterValueChanged(app, event)
            global mmc LED
            %Change filter to filter selected in dropdown menu
            FilterValue=find(strcmpi(app.Filter.Value,app.Filter.Items))-1;
            SetFilter(mmc, FilterValue)
            %If the livebutton is on and the filter is set to brightfield then open the shutter
            if app.LiveButton.Value==1 && ~strcmpi(app.Filter.Value,'Brightfield')
                mmc.setShutterOpen('Shutter',1);
            end
            %If the livebutton is on and the filter is set to brightfield, or if the overide box is checked then turn backlight to amps value
            if (app.LiveButton.Value==1 && strcmpi(app.Filter.Value,'Brightfield')) || (app.LiveOverrideCheckBox.Value==1)
                value = app.BFAmps.Value;
                powervalue=num2str(value);
                fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                pause(0.5)
                while LED.BytesAvailable>0
                    LEDMessage = fscanf(LED);
                end
            else
                %If not the above cases then turn the backlight off
                powervalue=num2str(0);
                fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                pause(0.5)
                while LED.BytesAvailable>0
                    LEDMessage = fscanf(LED);
                end
            end
            
        end

        % Value changed function: LiveOverrideCheckBox
        function LiveOverride(app, event)
            global LED
            value = app.LiveOverrideCheckBox.Value;
            %if override checkbox is checked then set brightfield light to the amps value
            if value==1
                value = app.BFAmps.Value;
                powervalue=num2str(value);
                fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                pause(0.5)
                while LED.BytesAvailable>0
                    LEDMessage = fscanf(LED);
                end
                %If the ovveride is unchecked and if the live button is checked and the filter is set to
                %brightfield then turn off the light
            elseif ~(app.LiveButton.Value==1 && strcmpi(app.Filter.Value,'Brightfield'))
                powervalue=num2str(0);
                fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                pause(0.5)
                while LED.BytesAvailable>0
                    LEDMessage = fscanf(LED);
                end
            end
        end

        % Button pushed function: ScanArray
        function ScanArrayButtonPushed(app, event)
            global mmc himage TIMER numA order m n pixel_size POS Centroid_cell q Array_Dim vid LED M1 user sys PFilters
            
            %Initialize global counter for tracking memory usage
            M1 = 1;
            
            %Close any open timers, set filter to brightfield, start live acquisition
            SetFilter(mmc,0)
            mmc.waitForSystem()
            if isa(TIMER,'timer')
                if isvalid(TIMER)
                    stop(TIMER)
                end
            end
            mmc.stopSequenceAcquisition();
            delete(timerfindall)
            if ~isempty('vid')
                vid=figure('Position', [1016 256 695 573]);
                View=axes(vid);
                axis off
            elseif ~ishandle(vid)
                vid=figure('Position', [1016 256 695 573]);
                View=axes(vid);
                axis off
            else
                View=vid.Children;
            end
            [TIMER,himage] = StartLiveHist(View,app.Histogram,app);
            app.LiveButton.Text='Stop';
            app.LiveButton.Value=1;
            
            %turn on brightfield light
            bfIntensity = app.BFAmps.Value;
            powervalue=num2str(bfIntensity);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
            %0: Bright Field
            %1: UV EX / Blue EM
            %2: Violet EX / Blue EM
            %3: Blue EX / Green EM
            %4: Green EX / Red EM
            %5: Red EX / Far Red EM
            
            app.Status.Value=sprintf('Timelapse Scan\nin Progress!');
            app.LiveButton.Enable='off';
            %Labels for the channel checkboxes
            CH1 = app.CHANNEL1.Value;
            CH2 = app.CHANNEL2.Value;
            CH3 = app.CHANNEL3.Value;
            CH4 = app.CHANNEL4.Value;
            CH5 = app.CHANNEL5.Value;
            
            %Create global variable to share filters selected with other GUI
            PFilters = [CH1 CH2 CH3 CH4 CH5];
            
            DIR = app.SaveLocation.Value;
            Size_Filt = [app.Cell_Lower.Value,app.Cell_Upper.Value,app.Clump_Max.Value];
            %             Timepoints = app.TimePoints.Value-1;
            Timepoints = app.TimePoints.Value;
            WaitTime = app.TotalDuration.Value*3600/Timepoints;
            numA = app.Array_Quant.Value;
            Array_Dim = [app.RaftWidth.Value,app.RaftGap.Value,app.ArraySize.Value];
            %Re calibrate correction --> Z calibration of prior stage to real world measurement
            correction = app.Well_Depth.Value*0.64;
            %Labels for the exposure values for each of the channels
            BF_Exposure = app.BFEXP.Value;
            CH1_Exposure = app.EXP1.Value;
            CH2_Exposure = app.EXP2.Value;
            CH3_Exposure = app.EXP3.Value;
            CH4_Exposure = app.EXP4.Value;
            CH5_Exposure = app.EXP5.Value;
            %Set the pixel sizes based on the objective and magnification settings  --> find empirically with micromanager
            %Pixel sizes for each objective based on magnificatoin setting
            objective = find(strcmpi(app.Objective.Value,app.Objective.Items));
            switch objective
                case 1
                    %Pixel_Sizes = [10.6578 8.4321 4.5915 3.8648 3.2366 3.1675 2.6194 2.0458 1.6283 1.2945 1.0439];
                    Pixel_Sizes = [10.6578 8.4321 4.5915 3.8648 3.2366 3.1675 2.636 2.0568 1.6353 1.2981 1.0446];
                case 2
                    %Pixel_Sizes = [5.1587 4.0625 3.2244 2.6037 2.0307 1.6308 1.3077 1.0262 0.8176 0.6505 0.5228];
                    Pixel_Sizes = [5.1587 4.0625 3.2244 2.6558 2.068 1.6535 1.3163 1.0257 0.8203 0.6522 0.5247];
            end
            Mag = find(strcmpi(app.Magnification.Value,app.Magnification.Items));
            pixel_size = Pixel_Sizes(Mag);
            
            %Set exposure to the brightfield exposure to do brightfield first
            mmc.setExposure(BF_Exposure);
            
            %img = get(himage,'CData');
            n = mmc.getImageWidth();
            m = mmc.getImageHeight();
            %[m,n] = size(img);
            %Accounting for image overlap-- to not cutoff rafts when moving between imaging positions
            height = m*pixel_size-sum(Array_Dim(1:2));
            width = n*pixel_size-sum(Array_Dim(1:2));
            %Estimating the distance between corners of an array
            est_move = ((Array_Dim(1)+Array_Dim(2))*Array_Dim(3))-Array_Dim(2)-((0.9*m)*pixel_size);
            %Creating two blank cell arrays -- the first is xyz positions for each field of view per array, the second is the autofocus points
            Positions = cell(numA,1);
            Plane_cell = cell(numA,1);
            
            %Make empty cell arra -- makes string array of channels checked
            FilterString = {};
            if CH1>0
                FilterString = [FilterString,{'Blue'}];
            end
            if CH2>0
                FilterString = [FilterString,{'Violet'}];
            end
            if CH3>0
                FilterString = [FilterString,{'Green'}];
            end
            if CH4>0
                FilterString = [FilterString,{'Red'}];
            end
            if CH5>0
                FilterString = [FilterString,{'Far Red'}];
            end
            FilterString = [FilterString,{'Bright Field'}];
            %Ask if you want to autofocus
            quest = questdlg('Do you want to use autofocus?','Autofocus query','Yes','No','No');
            %checks directory for previous data and asks if you want to load previous data
            if exist([DIR,'\data'],'dir')>0
                quest2 = questdlg('Do you want to load previous positions?','New Positions?','Yes','No','No');
            else
                %If no previous data exists then quest2=0
                quest2 = 'No';
            end
            
            %asks which channels you want to autofocus in (brightfield automatic)
            if strcmpi('Yes',quest)
                AF_select_temp = listdlg('ListString',FilterString,'SelectionMode','multiple','InitialValue',1,'PromptString','Select channel(s) for AutoFocus');
                AF_select = strcmpi(FilterString(AF_select_temp),'Blue')+strcmpi(FilterString(AF_select_temp),'Violet')*2+strcmpi(FilterString(AF_select_temp),'Green')*3+strcmpi(FilterString(AF_select_temp),'Red')*4+strcmpi(FilterString(AF_select_temp),'Far Red')*5+strcmpi(FilterString(AF_select_temp),'Bright Field')*6;
                AF_filters = zeros(1,6);
                AF_filters(AF_select) = 1;
                filt_select = 6;
                %If you don't want autofocus --> slect which channels to focus with for initial (and only) focusing timepoint
            elseif strcmpi(quest2,'No')
                filt_select = listdlg('ListString',FilterString,'SelectionMode','single','InitialValue',1,'PromptString','Select channel for focusing');
                switch FilterString{filt_select}
                    case 'Blue'
                        temp_exp = str2double(inputdlg('Exposure: ','Exposure',1,{num2str(CH1_Exposure)}));
                        filt_select = 1;
                    case 'Violet'
                        temp_exp = str2double(inputdlg('Exposure: ','Exposure',1,{num2str(CH2_Exposure)}));
                        filt_select = 2;
                    case 'Green'
                        temp_exp = str2double(inputdlg('Exposure: ','Exposure',1,{num2str(CH3_Exposure)}));
                        filt_select = 3;
                    case 'Red'
                        temp_exp = str2double(inputdlg('Exposure: ','Exposure',1,{num2str(CH4_Exposure)}));
                        filt_select = 4;
                    case 'Far Red'
                        temp_exp = str2double(inputdlg('Exposure: ','Exposure',1,{num2str(CH5_Exposure)}));
                        filt_select = 5;
                    otherwise
                        filt_select = 6;
                end
            end
            %Asks which channel you want to mask with-- i.e. masking with blue (hoechst nuclear stain) if other channels are less consistent/noisy/etc.
            Mask_dye = listdlg('ListString',FilterString,'SelectionMode','single','InitialValue',1,'PromptString','Select channel for masking');
            switch FilterString{Mask_dye}
                case 'Blue'
                    Mask_dye = 1;
                case 'Violet'
                    Mask_dye = 2;
                case 'Green'
                    Mask_dye = 3;
                case 'Red'
                    Mask_dye = 4;
                case 'Far Red'
                    Mask_dye = 5;
                otherwise
                    Mask_dye = [];
            end
            %Gets z position of stage
            mmc.getPosition('ZStage');
            %If you are loading data from a previous experiment then load the positions and planes
            if strcmpi(quest2,'Yes')
                load([DIR,'\data\Positions'])
                load([DIR,'\data\Plane_cell'])
                %If you are not loading data--> open the Four_Well app
            else
                %If you have a single well cassettes
                %                 if numA>1
                %                     if ~app.Four_Well.Value
                %                         z = Select_Well_Single();               %Need to change GUI
                %                         %If you have four well cassettes
                %                     else
                %                         z = Select_Well_4Well();
                %                     end
                %                     uiwait(z)
                %                 end
                for k = 1:numA
                    %                     if k>1
                    %                         if ~app.Four_Well.Value
                    %                             MoveToWell(mmc,topleft,order(k),order(k-1));
                    %                         else
                    %                             MoveToWell_4well(mmc,topleft,order(k),order(k-1));
                    %                         end
                    %                     end
                    %find the corners of the first array and focus
                    z = warndlg(['Array ',num2str(k),': Focus on top left of array']);
                    uiwait(z)
                    topleft = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                    mmc.waitForSystem();
                    
                    mmc.setXYPosition('XYStage',topleft(1)+est_move,topleft(2));
                    mmc.waitForSystem();
                    z = warndlg(['Array ',num2str(k),': Focus on top right of array']);
                    uiwait(z)
                    topright = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                    mmc.waitForSystem();
                    
                    mmc.setXYPosition('XYStage',topright(1),topright(2)+est_move);
                    mmc.waitForSystem();
                    z = warndlg(['Array ',num2str(k),': Focus on bottom right of array']);
                    uiwait(z)
                    bottomright = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                    mmc.waitForSystem();
                    
                    mmc.setXYPosition('XYStage',bottomright(1)-est_move,bottomright(2));
                    mmc.waitForSystem();
                    z = warndlg(['Array ',num2str(k),': Focus on bottom left of array']);
                    uiwait(z)
                    bottomleft = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                    mmc.waitForSystem();
                    
                    mmc.setXYPosition('XYStage',mean([topright(1),topleft(1),bottomright(1),bottomleft(1)]),mean([topleft(2),topright(2),bottomleft(2),bottomright(2)]));
                    mmc.waitForSystem();
                    z = warndlg(['Array ',num2str(k),': Focus on center of array']);
                    uiwait(z)
                    center = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                    mmc.waitForSystem();
                    
                    %If you selected to focus in other channels and didn't select autofocus --> move to corners and center and focus
                    if filt_select~=6
                        mmc.setExposure(temp_exp);
                        powervalue=num2str(0);
                        fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                        pause(0.5)
                        while LED.BytesAvailable>0
                            LEDMessage = fscanf(LED);
                        end
                        SetFilter(mmc,filt_select)
                        mmc.setShutterOpen('Shutter',1)
                        
                        mmc.setXYPosition('XYStage',topleft(1),topleft(2));
                        mmc.setPosition('ZStage',topleft(3));
                        mmc.waitForSystem();
                        z = warndlg(['Array ',num2str(k),': Focus on top left of array']);
                        uiwait(z)
                        topleft = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                        mmc.waitForSystem();
                        
                        mmc.setXYPosition('XYStage',topright(1),topright(2));
                        mmc.setPosition('ZStage',topright(3));
                        mmc.waitForSystem();
                        z = warndlg(['Array ',num2str(k),': Focus on top right of array']);
                        uiwait(z)
                        topright = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                        mmc.waitForSystem();
                        
                        mmc.setXYPosition('XYStage',bottomright(1),bottomright(2));
                        mmc.setPosition('ZStage',bottomright(3));
                        mmc.waitForSystem();
                        z = warndlg(['Array ',num2str(k),': Focus on bottom right of array']);
                        uiwait(z)
                        bottomright = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                        mmc.waitForSystem();
                        
                        mmc.setXYPosition('XYStage',bottomleft(1),bottomleft(2));
                        mmc.setPosition('ZStage',bottomleft(3));
                        mmc.waitForSystem();
                        z = warndlg(['Array ',num2str(k),': Focus on bottom left of array']);
                        uiwait(z)
                        bottomleft = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                        mmc.waitForSystem();
                        
                        mmc.setXYPosition('XYStage',center(1),center(2));
                        mmc.setPosition('ZStage',center(3));
                        mmc.waitForSystem();
                        z = warndlg(['Array ',num2str(k),': Focus on center of array']);
                        uiwait(z)
                        center = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                        mmc.waitForSystem();
                        
                        mmc.setExposure(BF_Exposure);
                        SetFilter(mmc,0)
                        mmc.setShutterOpen('Shutter',0)
                        
                        powervalue=num2str(bfIntensity);
                        fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                        pause(0.5)
                        while LED.BytesAvailable>0
                            LEDMessage = fscanf(LED);
                        end
                    end
                    
                    %Calculate the equation of the plane
                    Plane = [topleft;topright;bottomleft;bottomright;center];
                    %Calculate total field of view to image in
                    X_distance = topleft(1)-topright(1);
                    %X_distance = topleft(1)-min([topright(1), bottomright(1)]);
                    %X_distance = max([(topleft(1)-topright(1)),(topleft(1)-bottomright(1))]);
                    Y_distance = bottomleft(2)-topleft(2);
                    %Y_distance = bottomleft(2)-min([topleft(2), bottomright(2)]);
                    %Y_distance = max([(bottomleft(2)-topleft(2)),(bottomright(2)-topleft(2))]);
                    Fields_of_View = [ceil(abs(X_distance) / width), ceil(abs(Y_distance) / height)];
                    POS = zeros(Fields_of_View(2)+1,Fields_of_View(1)+1,3);
                    %Sets how the stage will move to account for an array being slanted
                    move_right = [(topright(1)-topleft(1))/Fields_of_View(1),(topright(2)-topleft(2))/Fields_of_View(1)];
                    move_down = [(bottomleft(1)-topleft(1))/Fields_of_View(2),(bottomleft(2)-topleft(2))/Fields_of_View(2)];
                    %Set the first position to the top left corner
                    POS(1,1,1) = topleft(1);
                    POS(1,1,2) = topleft(2);
                    POS(1,1,3) = topleft(3);
                    %Fit a thin plate spline to plane of the array
                    fittp = fit([Plane(:,1) Plane(:,2)], Plane(:,3), 'thinplateinterp');
                    %Change m and n to the number of fields of view per array
                    [m,n,~] = size(POS);
                    %Safety protocol to set the max plane to focus on, so that the objective doesn't hit the stage
                    minZ = min(Plane(:,3))-1000;
                    %Calculate fields of view (not including position 1,1)
                    for ii = 1:m
                        for j = 1:n
                            if ii ~=1 || j ~= 1
                                POS(ii,j,1) = POS(1,1,1) + (ii-1)*move_down(1) + (j-1)*move_right(1);
                                POS(ii,j,2) = POS(1,1,2) + (ii-1)*move_down(2) + (j-1)*move_right(2);
                                POS(ii,j,3) = fittp(POS(ii,j,1),POS(ii,j,2));
                                %Make sure that the Z stage never goes lower than specified
                                if POS(ii,j,3)<minZ
                                    POS(ii,j,3) = minZ;
                                end
                            end
                        end
                    end
                    %Save the corners and center planes for each array, then saves the field of view positions (xyz) for each array
                    Plane_cell{k} = Plane;
                    Positions{k} = POS;
                    
                    
                end
            end
            %Forces MATLAB to save variables as previous version types --> lower compression, faster save --> may not be needed anymore
            %Creates a data file for setup information in case it is needed later i.e. program crashing or re-scan
            Vars = {'Positions','Plane_cell','numA'};
            mkdir([DIR,'\data'])
            for i = 1:length(Vars)
                info = whos(Vars{i});
                if info.bytes<2^31
                    save([DIR,'\data\',Vars{i}],Vars{i},'-v6')
                else
                    save([DIR,'\data\',Vars{i}],Vars{i},'-v7.3')
                end
            end
            
            %Turn off live feed and turn off light --> prepare for scanning
            
            mmc.stopSequenceAcquisition();
            stop(TIMER)
            delete(timerfindall)
            close(vid)
            clear -global TIMER
            cla(app.Histogram)
            %if the ovverride checkbox is not checked then turn off brightfield light
            powervalue=num2str(0);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
            %Creating an empty folder for each position of each array --> file management
            for q = 1:numA
                [m,n,~] = size(Positions{q});
                for j = 1:m
                    for k = 1:n
                        mkdir([DIR,'\',num2str(q),'\',num2str(j),'x',num2str(k)])
                    end
                end
            end
            
            
            %stores locations segmented microrafts
            Loc_cell = cell(numA,Timepoints+1);
            Raft_locations_cell = cell(numA);
            DATA = cell(numA,Timepoints+1,5,8);
            L_cell = cell(numA,Timepoints+1);
            Centroid_cell = cell(numA,Timepoints+1);
            
            delete(gcp('nocreate'))
            p = parpool('Local8');
            
            % pause(5)
            SetFilter(mmc,3);
            mmc.waitForSystem()
            SetFilter(mmc,1);
            pause(1)
            SetFilter(mmc,0);
            mmc.setExposure(BF_Exposure);
            mmc.waitForSystem()
            
            Scan_Time = zeros(q,Timepoints+1,2);
            timer1 = tic;
            
            %Scan code
            user = cell(Timepoints+1,1);
            sys = cell(Timepoints+1,1);
            for i = 0:Timepoints
                %ets up how long you wait in between each scan point
                if i>0
                    waitb = waitbar(0,'');
                    T = toc(timer1)-(i*WaitTime);
                    while T<0
                        %update wait bar to show how much time is left before next scan
                        T = toc(timer1)-(i*WaitTime);
                        waitbar((WaitTime+T)/WaitTime,waitb,['Time until next scan: ',num2str(round(abs(T)/60)),' min']);
                        pause(1);
                    end
                    close(waitb)
                end
                
                for q = 1:numA
                    %Find the fields of view and previous position data
                    [m,n,~] = size(Positions{q});
                    POS_old = Positions{q};
                    %If you can autofocus then run this code
                    if strcmpi('Yes',quest)
                        Plane = Plane_cell{q};
                        timerrr2 = tic;
                        [~,~,Plane,POS_array] = AFocus(Plane,Mag,correction,pixel_size,Array_Dim(1),Array_Dim(2),POS_old,AF_filters,[CH1_Exposure CH2_Exposure CH3_Exposure CH4_Exposure CH5_Exposure BF_Exposure],objective,bfIntensity);
                        Scan_Time(q,i+1,1) = toc(timerrr2);
                        Positions{q} = POS_array{1};
                        Plane_cell{q} = Plane;
                        %If you did not select autofocus, sets the positions to previous positions
                    else
                        if CH1>0
                            POS_array{1} = POS_old;
                        end
                        if CH2>0
                            POS_array{2} = POS_old;
                        end
                        if CH3>0
                            POS_array{3} = POS_old;
                        end
                        if CH4>0
                            POS_array{4} = POS_old;
                        end
                        if CH5>0
                            POS_array{5} = POS_old;
                        end
                        POS_array{6} = POS_old;
                        %                         mmc.setExposure(BF_Exposure);
                        SetFilter(mmc,0);
                    end
                    
                    %         timerrr = tic;
                    mmc.setExposure(BF_Exposure);
                    mmc.setXYPosition('XYStage',POS_array{6}(1,1,1),POS_array{6}(1,1,2));
                    mmc.waitForSystem()
                    
                    [Scan_Time(q,i+1,2),L_cell{q,i+1},Centroid_cell{q,i+1},integ,area,cell_count,idx,intensity,integ_inblue,integ_total,cell_loc,camera_error_temp] = Array_Scan_t0(mmc,POS_array,m,n,[CH1 CH2 CH3 CH4 CH5],[CH1_Exposure CH2_Exposure CH3_Exposure CH4_Exposure CH5_Exposure BF_Exposure],DIR,pixel_size,q,i,p,Size_Filt,Array_Dim(1),Array_Dim(2),Mask_dye,bfIntensity);
                    %         Scan_Time(q,i+1,2) = toc(timerrr);
                    %Create the DATA matrix -- 1-5 are channels; 1-7 are parameters: integration, area, cell count, index,
                    %intensity, integration total, and integration in blue
                    % POS{array #}(field of view i, field of view j, coordinate)
                    POS = Positions{q};
                    [Loc_cell{q,i+1},Raft_locations,~] = Label_uraft(m,n,pixel_size,POS,Centroid_cell{q,i+1},q,Array_Dim,DIR);
                    %Monitor memory usage
                    %[user(i), sys(i)] = memory;
                    for ii = 1:m
                        for jj = 1:n
                            for CHANNEL = find([CH1 CH2 CH3 CH4 CH5])
                                %format of the data cell array: DATA{array, timepoint, channel, parameter}
                                DATA{q,i+1,CHANNEL,1} = [DATA{q,i+1,CHANNEL,1};integ{ii,jj,CHANNEL}];
                                DATA{q,i+1,CHANNEL,2} = [DATA{q,i+1,CHANNEL,2};area{ii,jj,CHANNEL}];
                                DATA{q,i+1,CHANNEL,3} = [DATA{q,i+1,CHANNEL,3};cell_count{ii,jj,CHANNEL}];
                                DATA{q,i+1,CHANNEL,4} = [DATA{q,i+1,CHANNEL,4};idx{ii,jj,CHANNEL}];
                                DATA{q,i+1,CHANNEL,5} = [DATA{q,i+1,CHANNEL,5};intensity{ii,jj,CHANNEL}];
                                DATA{q,i+1,CHANNEL,6} = [DATA{q,i+1,CHANNEL,6};integ_total{ii,jj,CHANNEL}];
                                DATA{q,i+1,CHANNEL,7} = [DATA{q,i+1,CHANNEL,7};integ_inblue{ii,jj,CHANNEL}];
                                DATA{q,i+1,CHANNEL,8} = [DATA{q,i+1,CHANNEL,8};cell_loc{ii,jj,CHANNEL}];
                            end
                        end
                    end
                    camera_error{q,i+1} = camera_error_temp;
                    if i == 0
                        Raft_locations_cell{q} = Raft_locations;
                    end
                    
                end
            end
            
            
            SetFilter(mmc,0);
            
            waitb = waitbar(0);
            Loc_cell2 = cell(numA,Timepoints+1);
            % Creates cell array to hold the data of the image: 7 an are for the 7 channels and 5 parameters
            ALL_DATA = cell(numA,8,5);
            %%%Average redundant microrafts --> microrafts that are in overlapping images
            for q = 1:numA
                Raft_locations = Raft_locations_cell{q};
                new_Loc = zeros(size(Raft_locations,1),4);
                
                for CHANNEL = find([CH1 CH2 CH3 CH4 CH5])
                    for meth = 1:8
                        if meth==4||meth==5||meth==8
                            ALL_DATA{q,meth,CHANNEL} = repmat(cell(1), size(Raft_locations,1),Timepoints+1);
                        else
                            ALL_DATA{q,meth,CHANNEL} = zeros(size(Raft_locations,1),Timepoints+1);
                        end
                    end
                end
                for i = 1:Timepoints+1
                    Loc = Loc_cell{q,i};
                    for ii = 1:length(Raft_locations)
                        ind = find(Loc(:,5)==ii);
                        if length(ind)>1
                            new_Loc(ii,:) = Loc(ind(1),1:4);
                            for CHANNEL = find([CH1 CH2 CH3 CH4 CH5])
                                for meth = 1:8
                                    if meth==4||meth==5||meth==8
                                        temp = cell(length(ind),1);
                                        for kk = 1:length(ind)
                                            temp(kk) = DATA{q,i,CHANNEL,meth}(ind(kk));
                                        end
                                        ALL_DATA{q,meth,CHANNEL}(ii,i) = {temp};
                                    else
                                        ALL_DATA{q,meth,CHANNEL}(ii,i) = mean(DATA{q,i,CHANNEL,meth}(ind));
                                    end
                                end
                            end
                        elseif ~isempty(ind)
                            new_Loc(ii,:) = Loc(ind,1:4);
                            for CHANNEL = find([CH1 CH2 CH3 CH4 CH5])
                                for meth = 1:8
                                    if meth==4||meth==5||meth==8
                                        ALL_DATA{q,meth,CHANNEL}(ii,i) = {DATA{q,i,CHANNEL,meth}(ind)};
                                    else
                                        ALL_DATA{q,meth,CHANNEL}(ii,i) = DATA{q,i,CHANNEL,meth}(ind);
                                    end
                                end
                            end
                        end
                    end
                    Loc_cell2{q,i} = new_Loc;
                    %size(Loc_cell2{q,i})
                end
            end
            
            clear DATA
            waitbar(1,waitb,'Saving Data...')
            
            Vars = {'Loc_cell','Loc_cell2','Centroid_cell','Positions','Plane_cell','Array_Dim','pixel_size','Raft_locations_cell','numA','Scan_Time','Size_Filt','camera_error'};
            mkdir([DIR,'\data'])
            for i = 1:length(Vars)
                info = whos(Vars{i});
                if info.bytes<2^31
                    save([DIR,'\data\',Vars{i}],Vars{i},'-v6')
                else
                    save([DIR,'\data\',Vars{i}],Vars{i},'-v7.3')
                end
            end
            
            mkdir([DIR,'\data\ALL_DATA\'])
            for i = 1:size(ALL_DATA,1)
                for j = 1:size(ALL_DATA,2)
                    for k = 1:size(ALL_DATA,3)
                        data = ALL_DATA{i,j,k};
                        if ~isempty(data)
                            info = whos('data');
                            if info.bytes<2^31
                                save([DIR,'\data\ALL_DATA\A',num2str(i),'_M',num2str(j),'_C',num2str(k)],'data','-v6')
                            else
                                save([DIR,'\data\ALL_DATA\A',num2str(i),'_M',num2str(j),'_C',num2str(k)],'data','-v7.3')
                            end
                        end
                    end
                end
            end
            
            mkdir([DIR,'\data\L_cell\'])
            for i = 1:size(L_cell,1)
                for j = 1:size(L_cell,2)
                    L_array = L_cell{i,j};
                    info = whos('L_array');
                    if info.bytes<2^31
                        save([DIR,'\data\L_cell\A',num2str(i),'_T',num2str(j)],'L_array','-v7')
                    else
                        save([DIR,'\data\L_cell\A',num2str(i),'_T',num2str(j)],'L_array','-v7.3')
                    end
                end
            end
            delete(p)
            close(waitb)
            
            app.LiveButton.Enable='on';
            app.LiveButton.Text='Live';
            app.LiveButton.Value=0;
            app.Status.Value=sprintf('Timelapse Complete!');
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %choose a directory to save into
            Dir = uigetdir(app.SaveLocation.Value);
            if Dir~=0
                %if the directory folder is a folder then change the text in the save location to the folder name
                if exist(Dir,'dir')==7
                    app.SaveLocation.Value=Dir;
                end
            end
        end

        % Value changed function: TotalDuration
        function TotalDurationValueChanged(app, event)
            value = app.TotalDuration.Value;
            %Change the interval based on the total duration divided by the number of time points
            app.Interval.Value=value/app.TimePoints.Value*60;
            
        end

        % Value changed function: Interval
        function IntervalValueChanged(app, event)
            value = app.Interval.Value;
            %Change the number of time points and round down to an integer value
            app.TimePoints.Value=floor(app.TotalDuration.Value/(value/60));
            %change the interval time according to the integer value of time points
            app.Interval.Value=app.TotalDuration.Value/app.TimePoints.Value*60;
            
        end

        % Value changed function: TimePoints
        function TimePointsValueChanged(app, event)
            value = floor(app.TimePoints.Value);
            %round the time points down to an integer value
            app.TimePoints.Value=value;
            %Change the interval value to the total duration divided by the number of time points
            app.Interval.Value=app.TotalDuration.Value/value*60;
        end

        % Value changed function: Exp
        function ExpValueChanged(app, event)
            global mmc
            mmc.setExposure(app.Exp.Value)
            %disp('test')
        end

        % Button pushed function: AutomatedSelectionButton
        function AutomatedSelectionButtonPushed(app, event)
            global PDIR PFilters
            
            %If the scan array function has not been ran, PFilters must be created here for automated selection
            %Labels for the channel checkboxes
            CH1 = app.CHANNEL1.Value;
            CH2 = app.CHANNEL2.Value;
            CH3 = app.CHANNEL3.Value;
            CH4 = app.CHANNEL4.Value;
            CH5 = app.CHANNEL5.Value;
            
            %Create global variable for selected filters
            PFilters = [CH1 CH2 CH3 CH4 CH5];
            
            PDIR = app.SaveLocation.Value;
            
            %Open GUI for select microrafts
            DIR = PDIR;
            if exist([DIR,'\data'],'dir')==0
                z = warndlg({'There is no  data in the file path; select a save location!'});
                uiwait(z)
            else
                GUI_SelectMicrorafts;
            end
            app.SelectMicroraftsLamp.Enable = 'on';
        end

        % Button pushed function: CalibrateNeedleButton
        function CalibrateNeedleButtonPushed(app, event)
            global himage TIMER mmc vid PDIR
            
            PDIR = app.SaveLocation.Value;
            
            %Open GUI for needle calibration
            GUI_CalibrateNeedle;
            
            %Open up a figure to display video feed from camera
            mmc.setExposure(app.Exp.Value);
            %find any active timers and stop them
            if isa(TIMER,'timer')
                if isvalid(TIMER)
                    stop(TIMER)
                end
            end
            
            delete(timerfindall)
            %Create external figure for viewing video feed -- set figure position, turn off axis tics
            vid=figure('Position', [1016 256 695 573]);
            View=axes(vid);
            axis off
            [TIMER,himage] = StartLiveHist(View,app.Histogram,app);
            
            app.CalibrateNeedleLamp.Enable = 'on';
        end

        % Button pushed function: CalibrateWandButton
        function CalibrateWandButtonPushed(app, event)
            global himage TIMER mmc vid PDIR
            
            PDIR = app.SaveLocation.Value;
            %Open GUI for wand calibration
            GUI_CalibrateWand
            
            %Open up a figure to display video feed from camera
            mmc.setExposure(app.Exp.Value)
            %find any active timers and stop them
            if isa(TIMER,'timer')
                if isvalid(TIMER)
                    stop(TIMER)
                end
            end
            
            delete(timerfindall)
            %Create external figure for viewing video feed -- set figure position, turn off axis tics
            vid=figure('Position', [1016 256 695 573]);
            View=axes(vid);
            axis off
            [TIMER,himage] = StartLiveHist(View,app.Histogram,app);
            
            app.CalibrateWandLamp.Enable = 'on';
        end

        % Button pushed function: IsolateMicroraftsButton
        function IsolateMicroraftsButtonPushed(app, event)
            global himage TIMER mmc vid PDIR
            %Open GUI for isolating microrafts
            PDIR = app.SaveLocation.Value;
            DIR = PDIR;
            %Check whether release data and calibration files have been created before proceeding
            %             if exist([DIR,'\Rel_Loc.mat'],'dir')==0
            %                 z = warndlg({'Please select microrafts to release!'});
            %             elseif exist([DIR,'\data\NeedlePos.mat'],'dir')==0
            %                 z = warndlg({'Pleaes calibrate needle first!'});
            %             elseif exist([DIR,'\data\WandPos.mat'],'dir')==0
            %                 z = warndlg({'Pleaes calibrate wand first!'});
            %             elseif exist([DIR,'\data\Registration.mat'],'dir')==0
            %                 z = warndlg({'There is no registration data!','Please rescan array before proceeding!'});
            %             else
            z = warndlg('Make sure that microraft array is uncovered!');
            %If all preceeding steps are complete, open the GUI for isolating microrafts
            GUI_IsolateMicrorafts;
            app.IsolateMicroraftsLamp.Enable = 'on';
            %             end
        end

        % Button pushed function: ReScanArrayButton
        function ReScanArrayButtonPushed(app, event)
            %             global mmc himage TIMER numA order m n pixel_size POS Centroid_cell q Array_Dim vid LED M1 user sys PFilters
            global mmc himage TIMER vid LED
            %Code for rescanning array after hydrogel encapsulation
            
            %Initialize global counter for tracking memory usage
            M1 = 1;
            
            %Close any open timers, set filter to brightfield, start live acquisition
            SetFilter(mmc,0)
            mmc.waitForSystem()
            if isa(TIMER,'timer')
                if isvalid(TIMER)
                    stop(TIMER)
                end
            end
            mmc.stopSequenceAcquisition();
            delete(timerfindall)
            if ~isempty('vid')
                vid=figure('Position', [1016 256 695 573]);
                View=axes(vid);
                axis off
            elseif ~ishandle(vid)
                vid=figure('Position', [1016 256 695 573]);
                View=axes(vid);
                axis off
            else
                View=vid.Children;
            end
            [TIMER,himage] = StartLiveHist(View,app.Histogram,app);
            
            %turn on brightfield light
            bfIntensity = app.BFAmps.Value;
            powervalue=num2str(bfIntensity);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
            app.Status.Value=sprintf('Rescan\nin Progress!');
            DIR = app.SaveLocation.Value;
            
            Size_Filt = [app.Cell_Lower.Value,app.Cell_Upper.Value];
            
            %If not loading from array_select, then use the value from the Array Number input box
            if app.LoadArraySelection.Value == 0
                arraySelection = app.ArrayNumberRescan.Value;
                numA = arraySelection;
            else
                load([DIR,'\Rel_Loc.mat'],'Rel_Loc','array_select');
                arraySelection = array_select;
                numA = arraySelection;
            end
            
            Array_Dim = [app.RaftWidth.Value,app.RaftGap.Value,app.ArraySize.Value];
            BF_Exposure = app.BFEXP.Value;
            
            objective = find(strcmpi(app.Objective.Value,app.Objective.Items));
            switch objective
                case 1
                    %Pixel_Sizes = [10.6578 8.4321 4.5915 3.8648 3.2366 3.1675 2.6194 2.0458 1.6283 1.2945 1.0439];
                    Pixel_Sizes = [10.6578 8.4321 4.5915 3.8648 3.2366 3.1675 2.636 2.0568 1.6353 1.2981 1.0446];
                case 2
                    %Pixel_Sizes = [5.1587 4.0625 3.2244 2.6037 2.0307 1.6308 1.3077 1.0262 0.8176 0.6505 0.5228];
                    Pixel_Sizes = [5.1587 4.0625 3.2244 2.6558 2.068 1.6535 1.3163 1.0257 0.8203 0.6522 0.5247];
            end
            
            Mag = find(strcmpi(app.Magnification.Value,app.Magnification.Items));
            pixel_size = Pixel_Sizes(Mag);
            
            %Set exposure to the brightfield exposure to do brightfield first
            mmc.setExposure(BF_Exposure);
            
            %img = get(himage,'CData');
            n = mmc.getImageWidth();
            m = mmc.getImageHeight();
            %[m,n] = size(img);
            %Accounting for image overlap-- to not cutoff rafts when moving between imaging positions
            height = m*pixel_size-sum(Array_Dim(1:2));
            width = n*pixel_size-sum(Array_Dim(1:2));
            %Estimating the distance between corners of an array
            est_move = ((Array_Dim(1)+Array_Dim(2))*Array_Dim(3))-Array_Dim(2)-((0.9*m)*pixel_size);
            
            for k = arraySelection
                %Creating two blank cell arrays -- the first is xyz positions for each field of view per array, the second is the autofocus points
                Positions = cell(numA,1);
                Plane_cell = cell(numA,1);
                %find the corners of the first array and focus
                z = warndlg(['Array ',num2str(k),': Focus on top left of array']);
                uiwait(z)
                topleft = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                mmc.waitForSystem();
                
                mmc.setXYPosition('XYStage',topleft(1)+est_move,topleft(2));
                mmc.waitForSystem();
                z = warndlg(['Array ',num2str(k),': Focus on top right of array']);
                uiwait(z)
                topright = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                mmc.waitForSystem();
                
                mmc.setXYPosition('XYStage',topright(1),topright(2)+est_move);
                mmc.waitForSystem();
                z = warndlg(['Array ',num2str(k),': Focus on bottom right of array']);
                uiwait(z)
                bottomright = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                mmc.waitForSystem();
                
                mmc.setXYPosition('XYStage',bottomright(1)-est_move,bottomright(2));
                mmc.waitForSystem();
                z = warndlg(['Array ',num2str(k),': Focus on bottom left of array']);
                uiwait(z)
                bottomleft = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                mmc.waitForSystem();
                
                mmc.setXYPosition('XYStage',mean([topright(1),topleft(1),bottomright(1),bottomleft(1)]),mean([topleft(2),topright(2),bottomleft(2),bottomright(2)]));
                mmc.waitForSystem();
                z = warndlg(['Array ',num2str(k),': Focus on center of array']);
                uiwait(z)
                center = [mmc.getXPosition('XYStage'),mmc.getYPosition('XYStage'),mmc.getPosition('ZStage')];
                mmc.waitForSystem();
                
                
                %Calculate the equation of the plane
                Plane = [topleft;topright;bottomleft;bottomright;center];
                %Calculate total field of view to image in
                X_distance = topleft(1)-topright(1);
                Y_distance = bottomleft(2)-topleft(2);
                Fields_of_View = [ceil(abs(X_distance) / width), ceil(abs(Y_distance) / height)];
                POS = zeros(Fields_of_View(2)+1,Fields_of_View(1)+1,3);
                %Sets how the stage will move to account for an array being slanted
                move_right = [(topright(1)-topleft(1))/Fields_of_View(1),(topright(2)-topleft(2))/Fields_of_View(1)];
                move_down = [(bottomleft(1)-topleft(1))/Fields_of_View(2),(bottomleft(2)-topleft(2))/Fields_of_View(2)];
                %Set the first position to the top left corner
                POS(1,1,1) = topleft(1);
                POS(1,1,2) = topleft(2);
                POS(1,1,3) = topleft(3);
                %Fit a thin plate spline to plane of the array
                fittp = fit([Plane(:,1) Plane(:,2)], Plane(:,3), 'thinplateinterp');
                %Change m and n to the number of fields of view per array
                [m,n,~] = size(POS);
                %Safety protocol to set the max plane to focus on, so that the objective doesn't hit the stage
                minZ = min(Plane(:,3))-1000;
                %Calculate fields of view (not including position 1,1)
                for ii = 1:m
                    for j = 1:n
                        if ii ~=1 || j ~= 1
                            POS(ii,j,1) = POS(1,1,1) + (ii-1)*move_down(1) + (j-1)*move_right(1);
                            POS(ii,j,2) = POS(1,1,2) + (ii-1)*move_down(2) + (j-1)*move_right(2);
                            POS(ii,j,3) = fittp(POS(ii,j,1),POS(ii,j,2));
                            %Make sure that the Z stage never goes lower than specified
                            if POS(ii,j,3)<minZ
                                POS(ii,j,3) = minZ;
                            end
                        end
                    end
                end
                
                %Save the corners and center planes for each array, then saves the field of view positions (xyz) for each array
                Plane_cell{k} = Plane;
                Positions{k} = POS;
                %Save the fit plane; used in the Isolate GUI to adjust needle stroke
                fittp_cell{k} = fittp;
            end
            
            
            %Turn off live feed and turn off light --> prepare for scanning
            
            mmc.stopSequenceAcquisition();
            stop(TIMER)
            delete(timerfindall)
            close(vid)
            clear -global TIMER
            cla(app.Histogram)
            % turn off brightfield light
            powervalue=num2str(0);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
            %stores locations segmented microrafts
            Loc_cell = cell(numA,1);
            Raft_locations_cell = cell(numA);
            L_cell = cell(numA,1);
            Centroid_cell = cell(numA,1);
            
            %Create a parallel pool for scanning each array
            delete(gcp('nocreate'))
            p = parpool('Local8');
            
            waitb = waitbar(0,'');
            
            %turn on brightfield light
            bfIntensity = app.BFAmps.Value;
            powervalue=num2str(bfIntensity);
            fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
            pause(0.5)
            while LED.BytesAvailable>0
                LEDMessage = fscanf(LED);
            end
            
            for q = arraySelection
                
                waitbar((q/numA),waitb,['Rescanning array ',num2str(q),' of ',num2str(numA)]);
                
                [m,n,~] = size(Positions{q});
                POS_old = Positions{q};
                POS_array{6} = POS_old;
                
                SetFilter(mmc,0);
                
                mmc.setExposure(BF_Exposure);
                mmc.setXYPosition('XYStage',POS_array{6}(1,1,1),POS_array{6}(1,1,2));
                mmc.waitForSystem()
                %set timepoint to 1 for array scan t0
                i = 1;
                
                %Run scan in BF only and do not save images
                %%%[Scan_Time(q,i+1,2),L_cell{q,i+1},Centroid_cell{q,i+1},integ,area,cell_count,idx,intensity,integ_inblue,integ_total] = Array_Scan_t0(mmc,POS_array,m,n,[CH1 CH2 CH3 CH4 CH5],[CH1_Exposure CH2_Exposure CH3_Exposure CH4_Exposure CH5_Exposure BF_Exposure],DIR,pixel_size,q,i,p,Size_Filt,Array_Dim(1),Array_Dim(2),Mask_dye,bfIntensity);
                
                [~,L_cell{q,1},Centroid_cell{q,1},~,~,~,~,~,~,~,~] = Array_Scan_t0(mmc,POS_array,m,n,[0 0 0 0 0],[0 0 0 0 0 BF_Exposure],[],pixel_size,q,i,p,Size_Filt,Array_Dim(1),Array_Dim(2),[],bfIntensity);
                
                %Create the DATA matrix -- 1-5 are channels; 1-7 are parameters: integration, area, cell count, index,
                %intensity, integration total, and integration in blue
                % POS{array #}(field of view i, field of view j, coordinate)
                POS = Positions{q};
                [Loc_cell{q,1},Raft_locations,~] = Label_uraft(m,n,pixel_size,POS,Centroid_cell{q,i},q,Array_Dim,DIR);
                
                %Fill in estimated locations for missing rafts, only if correct # of rafts identified
                if size(Raft_locations,1) == Array_Dim(3)^2
                    Raft_locations = fill_missing_rafts(Raft_locations,Array_Dim);
                end
                
                Raft_locations_cell{q} = Raft_locations;
            end
            
            if exist([DIR,'\data'],'dir')
                save([DIR,'\data\Registration'],'Raft_locations_cell','Plane_cell','pixel_size','Positions','fittp_cell','POS','Centroid_cell','Array_Dim');
            else
                mkdir([DIR,'\data'])
                save([DIR,'\data\Registration'],'Raft_locations_cell','Plane_cell','pixel_size','Positions','fittp_cell','POS','Centroid_cell','Array_Dim');
            end
            
            delete(p)
            app.Status.Value=sprintf('Rescan Complete!');
            app.RescanLamp.Enable = 'on';
            
        end

        % Value changed function: Array_Quant
        function Array_QuantValueChanged(app, event)
            value = app.Array_Quant.Value;
            %Update the quantity of arrays to rescan in collection mode
            app. ArrayNumberRescan.Value = value;
        end

        % Button pushed function: LiveScreenButton
        function LiveScreenButtonPushed(app, event)
            global himage TIMER mmc vid LED PDIR PFilters
            
            PDIR = app.SaveLocation.Value;
            %If the scan array function has not been ran, PFilters must be created here for automated selection
            %Labels for the channel checkboxes
            CH1 = app.CHANNEL1.Value;
            CH2 = app.CHANNEL2.Value;
            CH3 = app.CHANNEL3.Value;
            CH4 = app.CHANNEL4.Value;
            CH5 = app.CHANNEL5.Value;
            
            %Create global variable for selected filters
            PFilters = [CH1 CH2 CH3 CH4 CH5];
            
            %Open GUI for select microrafts
            DIR = app.SaveLocation.Value;
            if exist([DIR,'\Release_Data.mat'],'file')==0
                z = warndlg({'No target microraft list exists!'});
                uiwait(z)
            else
                GUI_LiveScreen;
                %%%%%%%%%%%%%%% Turn on live video feed and light %%%%%%%%%%%%%%%%%%%%%%
                %Open up a figure to display video feed from camera
                mmc.setExposure(app.Exp.Value);
                %find any active timers and stop them
                if isa(TIMER,'timer')
                    if isvalid(TIMER)
                        stop(TIMER)
                    end
                end
                
                delete(timerfindall)
                %Create external figure for viewing video feed -- set figure position, turn off axis tics
                vid=figure('Position', [1016 256 695 573]);
                View=axes(vid);
                axis off
                [TIMER,himage] = StartLiveHist(View,app.Histogram,app);
                
                hold on
                plot(1024,1024,'s','MarkerSize',85,'MarkerEdgeColor','r')
                
                %Turn on LED backlight
                powervalue=num2str(400);
                fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                pause(0.5)
                while LED.BytesAvailable>0
                    LEDMessage = fscanf(LED);
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            end
            
        end

        % Button pushed function: HomeStageButton
        function HomeStageButtonPushed(app, event)
            global mmc
            f = uifigure;
            selection = uiconfirm(f, 'Would you like to home stage?', 'Confirm Home Position', 'Options', {'Yes','No'},...
                'DefaultOption',2);
            close(f)
            
            switch selection
                case 'Yes'
                    mmc.setXYPosition('XYStage',0,0);
                    mmc.waitForSystem()
                case 'No'
            end
        end

        % Button pushed function: CheckforCellsButton
        function CheckforCellsButtonPushed(app, event)
                        global himage TIMER mmc vid LED PDIR PFilters
            
            PDIR = app.SaveLocation.Value;
            %If the scan array function has not been ran, PFilters must be created here for automated selection
            %Labels for the channel checkboxes
            CH1 = app.CHANNEL1.Value;
            CH2 = app.CHANNEL2.Value;
            CH3 = app.CHANNEL3.Value;
            CH4 = app.CHANNEL4.Value;
            CH5 = app.CHANNEL5.Value;
            
            %Create global variable for selected filters
            PFilters = [CH1 CH2 CH3 CH4 CH5];
            
            %Open GUI for select microrafts
            DIR = app.SaveLocation.Value;
            if exist([DIR,'\Rel_Loc.mat'],'file')==0
                z = warndlg({'No release locations list exists!'});
                uiwait(z)
            elseif exist([DIR,'\data\Registration.mat'],'file')==0
                z = warndlg({'Must re-scan array first!'});
                uiwait(z)
            else
                GUI_LiveCheck;
                %%%%%%%%%%%%%%% Turn on live video feed and light %%%%%%%%%%%%%%%%%%%%%%
                %Open up a figure to display video feed from camera
                mmc.setExposure(app.Exp.Value);
                %find any active timers and stop them
                if isa(TIMER,'timer')
                    if isvalid(TIMER)
                        stop(TIMER)
                    end
                end
                
                delete(timerfindall)
                %Create external figure for viewing video feed -- set figure position, turn off axis tics
                vid=figure('Position', [1016 256 695 573]);
                View=axes(vid);
                axis off
                [TIMER,himage] = StartLiveHist(View,app.Histogram,app);
                
                hold on
                plot(1024,1024,'s','MarkerSize',85,'MarkerEdgeColor','r')
                
                %Turn on LED backlight
                powervalue=num2str(300);
                fwrite(LED,['SET:LEVEL:CHANNEL1,' powervalue ';SET:LEVEL:CHANNEL2,' powervalue ';'])
                pause(0.5)
                while LED.BytesAvailable>0
                    LEDMessage = fscanf(LED);
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ScanModeTab = uitab(app.TabGroup);
            app.ScanModeTab.Title = 'Scan Mode';
            app.ScanModeLabel = uilabel(app.ScanModeTab);
            app.ScanModeLabel.Text = 'Scan Mode';
            app.Histogram = uiaxes(app.ScanModeTab);
            title(app.Histogram, 'Histogram')
            app.ExposuremsLabel = uilabel(app.ScanModeTab);
            app.ExposuremsLabel.Text = 'Exposure (ms)';
            app.Exp = uieditfield(app.ScanModeTab, 'numeric');
            app.Exp.Limits = [3.1 10000];
            app.Exp.ValueChangedFcn = createCallbackFcn(app, @ExpValueChanged, true);
            app.Exp.Value = 40;
            app.MagnificationDropDownLabel = uilabel(app.ScanModeTab);
            app.MagnificationDropDownLabel.Text = 'Magnification';
            app.Magnification = uidropdown(app.ScanModeTab);
            app.Magnification.Items = {'0.63', '0.8', '1', '1.25', '1.6', '2', '2.5', '3.2', '4', '5', '6.3'};
            app.Magnification.Value = '3.2';
            app.ObjectiveDropDownLabel = uilabel(app.ScanModeTab);
            app.ObjectiveDropDownLabel.Text = 'Objective';
            app.Objective = uidropdown(app.ScanModeTab);
            app.Objective.Items = {'1x', '2x'};
            app.Objective.Value = '2x';
            app.FilterDropDownLabel = uilabel(app.ScanModeTab);
            app.FilterDropDownLabel.Text = 'Filter';
            app.Filter = uidropdown(app.ScanModeTab);
            app.Filter.Items = {'Brightfield', 'UV/Blue', 'Violet/Blue', 'Green', 'Red', 'Far Red'};
            app.Filter.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Filter.Value = 'Brightfield';
            app.SaveLocationEditFieldLabel = uilabel(app.ScanModeTab);
            app.SaveLocationEditFieldLabel.Text = 'Save Location';
            app.SaveLocation = uieditfield(app.ScanModeTab, 'text');
            app.SaveLocation.Value = 'D:\MVX\Documents\Cody\MVX Experiment Data';
            app.ScanArray = uibutton(app.ScanModeTab, 'push');
            app.ScanArray.ButtonPushedFcn = createCallbackFcn(app, @ScanArrayButtonPushed, true);
            app.ScanArray.Text = 'Scan Array';
            app.LiveButton = uibutton(app.ScanModeTab, 'state');
            app.LiveButton.ValueChangedFcn = createCallbackFcn(app, @LiveButtonValueChanged, true);
            app.LiveButton.Text = 'Live';
            app.TestCodeButton = uibutton(app.ScanModeTab, 'push');
            app.TestCodeButton.ButtonPushedFcn = createCallbackFcn(app, @TestCodeButtonPushed, true);
            app.TestCodeButton.Text = 'Test Code';
            app.BrowseButton = uibutton(app.ScanModeTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ArrayInfoPanel = uipanel(app.ScanModeTab);
            app.ArrayInfoPanel.Title = 'Array Info';
            app.Four_Well = uicheckbox(app.ArrayInfoPanel);
            app.Four_Well.Text = 'Four_Well';
            app.RaftSizeumEditFieldLabel = uilabel(app.ArrayInfoPanel);
            app.RaftSizeumEditFieldLabel.Text = 'Raft Size (um)';
            app.RaftWidth = uieditfield(app.ArrayInfoPanel, 'numeric');
            app.RaftWidth.Limits = [1 500];
            app.RaftWidth.Value = 100;
            app.RaftGapumEditFieldLabel = uilabel(app.ArrayInfoPanel);
            app.RaftGapumEditFieldLabel.Text = 'Raft Gap (um)';
            app.RaftGap = uieditfield(app.ArrayInfoPanel, 'numeric');
            app.RaftGap.Limits = [1 500];
            app.RaftGap.Value = 50;
            app.WellDepthumEditFieldLabel = uilabel(app.ArrayInfoPanel);
            app.WellDepthumEditFieldLabel.Text = 'Well Depth (um)';
            app.Well_Depth = uieditfield(app.ArrayInfoPanel, 'numeric');
            app.Well_Depth.Limits = [1 1000];
            app.Well_Depth.Value = 200;
            app.ArrayWidthLabel = uilabel(app.ArrayInfoPanel);
            app.ArrayWidthLabel.Text = 'Array Width (#)';
            app.ArraySize = uieditfield(app.ArrayInfoPanel, 'numeric');
            app.ArraySize.Limits = [1 1000];
            app.ArraySize.Value = 140;
            app.QuantityEditFieldLabel = uilabel(app.ArrayInfoPanel);
            app.QuantityEditFieldLabel.Text = 'Quantity';
            app.Array_Quant = uieditfield(app.ArrayInfoPanel, 'numeric');
            app.Array_Quant.Limits = [1 6];
            app.Array_Quant.ValueChangedFcn = createCallbackFcn(app, @Array_QuantValueChanged, true);
            app.Array_Quant.Value = 1;
            app.ExposurePanel = uipanel(app.ScanModeTab);
            app.ExposurePanel.Title = 'Exposure';
            app.CHANNEL1 = uicheckbox(app.ExposurePanel);
            app.CHANNEL1.Text = '';
            app.CHANNEL1.Value = true;
            app.CHANNEL2 = uicheckbox(app.ExposurePanel);
            app.CHANNEL2.Text = '';
            app.CHANNEL3 = uicheckbox(app.ExposurePanel);
            app.CHANNEL3.Text = '';
            app.CHANNEL3.Value = true;
            app.CHANNEL4 = uicheckbox(app.ExposurePanel);
            app.CHANNEL4.Text = '';
            app.CHANNEL4.Value = true;
            app.CHANNEL5 = uicheckbox(app.ExposurePanel);
            app.CHANNEL5.Text = '';
            app.UVBLabel = uilabel(app.ExposurePanel);
            app.UVBLabel.Text = 'UV/B';
            app.EXP1 = uieditfield(app.ExposurePanel, 'numeric');
            app.EXP1.Limits = [3.1 10000];
            app.EXP1.Value = 125;
            app.VBlueLabel = uilabel(app.ExposurePanel);
            app.VBlueLabel.Text = 'V/Blue';
            app.EXP2 = uieditfield(app.ExposurePanel, 'numeric');
            app.EXP2.Limits = [3.1 10000];
            app.EXP2.Value = 200;
            app.GreenEditFieldLabel = uilabel(app.ExposurePanel);
            app.GreenEditFieldLabel.Text = 'Green';
            app.EXP3 = uieditfield(app.ExposurePanel, 'numeric');
            app.EXP3.Limits = [3.1 10000];
            app.EXP3.Value = 200;
            app.RedEditFieldLabel = uilabel(app.ExposurePanel);
            app.RedEditFieldLabel.Text = 'Red';
            app.EXP4 = uieditfield(app.ExposurePanel, 'numeric');
            app.EXP4.Limits = [3.1 10000];
            app.EXP4.Value = 150;
            app.FarRedEditFieldLabel = uilabel(app.ExposurePanel);
            app.FarRedEditFieldLabel.Text = 'Far Red';
            app.EXP5 = uieditfield(app.ExposurePanel, 'numeric');
            app.EXP5.Limits = [3.1 10000];
            app.EXP5.Value = 200;
            app.BrightfieldEditFieldLabel = uilabel(app.ExposurePanel);
            app.BrightfieldEditFieldLabel.Text = 'Brightfield';
            app.BFEXP = uieditfield(app.ExposurePanel, 'numeric');
            app.BFEXP.Limits = [3.1 10000];
            app.BFEXP.Value = 40;
            app.SizeFiltersPanel = uipanel(app.ScanModeTab);
            app.SizeFiltersPanel.Title = 'Size Filters';
            app.CellandClumpRadiusumLabel = uilabel(app.SizeFiltersPanel);
            app.CellandClumpRadiusumLabel.Text = 'Cell and Clump Radius (um)';
            app.LowerEditFieldLabel = uilabel(app.SizeFiltersPanel);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.Cell_Lower = uieditfield(app.SizeFiltersPanel, 'numeric');
            app.Cell_Lower.Limits = [0 Inf];
            app.Cell_Lower.Value = 2;
            app.UpperEditFieldLabel = uilabel(app.SizeFiltersPanel);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.Cell_Upper = uieditfield(app.SizeFiltersPanel, 'numeric');
            app.Cell_Upper.Limits = [0 Inf];
            app.Cell_Upper.Value = 8;
            app.ClumpEditFieldLabel = uilabel(app.SizeFiltersPanel);
            app.ClumpEditFieldLabel.Text = 'Clump';
            app.Clump_Max = uieditfield(app.SizeFiltersPanel, 'numeric');
            app.Clump_Max.Limits = [0 50];
            app.Clump_Max.ValueDisplayFormat = '%.0f';
            app.Clump_Max.Value = 25;
            app.TimelapsePanel = uipanel(app.ScanModeTab);
            app.TimelapsePanel.Title = 'Timelapse';
            app.TotalDurationhEditFieldLabel = uilabel(app.TimelapsePanel);
            app.TotalDurationhEditFieldLabel.Text = 'Total Duration (h)';
            app.TotalDuration = uieditfield(app.TimelapsePanel, 'numeric');
            app.TotalDuration.Limits = [0 Inf];
            app.TotalDuration.ValueDisplayFormat = '%.2f';
            app.TotalDuration.ValueChangedFcn = createCallbackFcn(app, @TotalDurationValueChanged, true);
            app.TotalDuration.Value = 3;
            app.IntervalminEditFieldLabel = uilabel(app.TimelapsePanel);
            app.IntervalminEditFieldLabel.Text = 'Interval (min)';
            app.Interval = uieditfield(app.TimelapsePanel, 'numeric');
            app.Interval.Limits = [0 Inf];
            app.Interval.ValueDisplayFormat = '%.1f';
            app.Interval.ValueChangedFcn = createCallbackFcn(app, @IntervalValueChanged, true);
            app.Interval.Value = 60;
            app.NumberofTimepointsEditFieldLabel = uilabel(app.TimelapsePanel);
            app.NumberofTimepointsEditFieldLabel.Text = 'Number of Timepoints';
            app.TimePoints = uieditfield(app.TimelapsePanel, 'numeric');
            app.TimePoints.Limits = [0 Inf];
            app.TimePoints.ValueDisplayFormat = '%.0f';
            app.TimePoints.ValueChangedFcn = createCallbackFcn(app, @TimePointsValueChanged, true);
            app.TimePoints.Value = 3;
            app.BrifghtfieldIntensitySpinnerLabel = uilabel(app.ScanModeTab);
            app.BrifghtfieldIntensitySpinnerLabel.Text = 'Brifghtfield Intensity';
            app.BFAmps = uispinner(app.ScanModeTab);
            app.BFAmps.Limits = [0 600];
            app.BFAmps.ValueChangedFcn = createCallbackFcn(app, @BFAmpsValueChanged, true);
            app.BFAmps.Value = 120;
            app.LiveOverrideCheckBox = uicheckbox(app.ScanModeTab);
            app.LiveOverrideCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveOverride, true);
            app.LiveOverrideCheckBox.Text = 'Live Override';
            app.StatusLampLabel = uilabel(app.ScanModeTab);
            app.StatusLampLabel.Enable = 'off';
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ScanModeTab);
            app.StatusLamp.Enable = 'off';
            app.CollectionModeTab = uitab(app.TabGroup);
            app.CollectionModeTab.Title = 'Collection Mode';
            app.CollectionModeLabel = uilabel(app.CollectionModeTab);
            app.CollectionModeLabel.Text = 'Collection Mode';
            app.AutomatedSelectionButton = uibutton(app.CollectionModeTab, 'push');
            app.AutomatedSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AutomatedSelectionButtonPushed, true);
            app.AutomatedSelectionButton.Text = 'Automated Selection';
            app.ReScanArrayButton = uibutton(app.CollectionModeTab, 'push');
            app.ReScanArrayButton.ButtonPushedFcn = createCallbackFcn(app, @ReScanArrayButtonPushed, true);
            app.ReScanArrayButton.Text = 'Re-Scan Array';
            app.CalibrateNeedleButton = uibutton(app.CollectionModeTab, 'push');
            app.CalibrateNeedleButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateNeedleButtonPushed, true);
            app.CalibrateNeedleButton.Text = 'Calibrate Needle';
            app.CalibrateWandButton = uibutton(app.CollectionModeTab, 'push');
            app.CalibrateWandButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateWandButtonPushed, true);
            app.CalibrateWandButton.Text = 'Calibrate Wand';
            app.IsolateMicroraftsButton = uibutton(app.CollectionModeTab, 'push');
            app.IsolateMicroraftsButton.ButtonPushedFcn = createCallbackFcn(app, @IsolateMicroraftsButtonPushed, true);
            app.IsolateMicroraftsButton.Text = 'Isolate Microrafts';
            app.Label = uilabel(app.CollectionModeTab);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.SelectMicroraftsLamp = uilamp(app.CollectionModeTab);
            app.SelectMicroraftsLamp.Enable = 'off';
            app.RescanLamp = uilamp(app.CollectionModeTab);
            app.RescanLamp.Enable = 'off';
            app.Lamp_4Label = uilabel(app.CollectionModeTab);
            app.Lamp_4Label.Enable = 'off';
            app.Lamp_4Label.Text = '';
            app.CalibrateNeedleLamp = uilamp(app.CollectionModeTab);
            app.CalibrateNeedleLamp.Enable = 'off';
            app.Lamp_5Label = uilabel(app.CollectionModeTab);
            app.Lamp_5Label.Enable = 'off';
            app.Lamp_5Label.Text = '';
            app.CalibrateWandLamp = uilamp(app.CollectionModeTab);
            app.CalibrateWandLamp.Enable = 'off';
            app.Lamp_6Label = uilabel(app.CollectionModeTab);
            app.Lamp_6Label.Enable = 'off';
            app.Lamp_6Label.Text = '';
            app.IsolateMicroraftsLamp = uilamp(app.CollectionModeTab);
            app.IsolateMicroraftsLamp.Enable = 'off';
            app.ArrayNumberLabel = uilabel(app.CollectionModeTab);
            app.ArrayNumberLabel.Text = 'Array Number';
            app.ArrayNumberRescan = uieditfield(app.CollectionModeTab, 'numeric');
            app.ArrayNumberRescan.Limits = [1 6];
            app.ArrayNumberRescan.ValueDisplayFormat = '%.0f';
            app.ArrayNumberRescan.Value = 1;
            app.LiveScreenButton = uibutton(app.CollectionModeTab, 'push');
            app.LiveScreenButton.ButtonPushedFcn = createCallbackFcn(app, @LiveScreenButtonPushed, true);
            app.LiveScreenButton.Text = 'Live Screen';
            app.LoadArraySelection = uicheckbox(app.CollectionModeTab);
            app.LoadArraySelection.Text = 'Load array selection';
            app.HomeStageButton = uibutton(app.CollectionModeTab, 'push');
            app.HomeStageButton.ButtonPushedFcn = createCallbackFcn(app, @HomeStageButtonPushed, true);
            app.HomeStageButton.Text = 'Home Stage';
            app.CheckforCellsButton = uibutton(app.CollectionModeTab, 'push');
            app.CheckforCellsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckforCellsButtonPushed, true);
            app.CheckforCellsButton.Text = 'Check for Cells';
            app.StatusInfoTextAreaLabel = uilabel(app.UIFigure);
            app.StatusInfoTextAreaLabel.Text = 'Status Info';
            app.Status = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = InvertedMVXGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end